classdef MultitaskingReconApp_qMATCH_R2022b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MultitaskingReconUIFigure       matlab.ui.Figure
        SliceThicknessmmEditField       matlab.ui.control.NumericEditField
        SliceThicknessmmEditFieldLabel  matlab.ui.control.Label
        RefreshButton                   matlab.ui.control.Button
        LicenseIDLabel                  matlab.ui.control.Label
        FOVpemmEditField                matlab.ui.control.NumericEditField
        FOVpemmEditFieldLabel           matlab.ui.control.Label
        FlipAngleEditField              matlab.ui.control.EditField
        FlipAngleEditFieldLabel         matlab.ui.control.Label
        ExportDICOMButton               matlab.ui.control.Button
        SaveWorkspaceButton             matlab.ui.control.Button
        LoadReconSettingButton          matlab.ui.control.Button
        DCECheckBox                     matlab.ui.control.CheckBox
        ImagingAreaButtonGroup          matlab.ui.container.ButtonGroup
        HeadNeckButton                  matlab.ui.control.RadioButton
        AbdomenButton                   matlab.ui.control.RadioButton
        ChestButton                     matlab.ui.control.RadioButton
        NkzEditField                    matlab.ui.control.NumericEditField
        NkzEditFieldLabel               matlab.ui.control.Label
        NkyEditField                    matlab.ui.control.NumericEditField
        NkyEditFieldLabel               matlab.ui.control.Label
        NEchoEditField                  matlab.ui.control.NumericEditField
        NEchoEditFieldLabel             matlab.ui.control.Label
        LinesperShotEditField           matlab.ui.control.NumericEditField
        LinesperShotEditFieldLabel      matlab.ui.control.Label
        T1rhoprepmsEditField            matlab.ui.control.EditField
        T1rhoprepmsEditFieldLabel       matlab.ui.control.Label
        SGBlockEditField                matlab.ui.control.NumericEditField
        SGBlockEditFieldLabel           matlab.ui.control.Label
        MBFactorEditField               matlab.ui.control.NumericEditField
        MBFactorEditFieldLabel          matlab.ui.control.Label
        ScanTypeEditField               matlab.ui.control.EditField
        ScanTypeEditFieldLabel          matlab.ui.control.Label
        UseGPUCheckBox                  matlab.ui.control.CheckBox
        IREditField                     matlab.ui.control.EditField
        IREditFieldLabel                matlab.ui.control.Label
        T2IRmsEditField                 matlab.ui.control.EditField
        T2IRmsEditFieldLabel            matlab.ui.control.Label
        NcoilsEditField                 matlab.ui.control.NumericEditField
        NcoilsEditFieldLabel            matlab.ui.control.Label
        NzEditField                     matlab.ui.control.NumericEditField
        NzEditFieldLabel                matlab.ui.control.Label
        NyEditField                     matlab.ui.control.NumericEditField
        NyEditFieldLabel                matlab.ui.control.Label
        NxEditField                     matlab.ui.control.NumericEditField
        NxEditFieldLabel                matlab.ui.control.Label
        FOVrommEditField                matlab.ui.control.NumericEditField
        FOVrommEditFieldLabel           matlab.ui.control.Label
        NkxEditField                    matlab.ui.control.NumericEditField
        NkxEditFieldLabel               matlab.ui.control.Label
        NAcqEditField                   matlab.ui.control.NumericEditField
        NAcqEditFieldLabel              matlab.ui.control.Label
        EchospacingmsEditField          matlab.ui.control.NumericEditField
        EchospacingmsEditFieldLabel     matlab.ui.control.Label
        TEmsEditField                   matlab.ui.control.NumericEditField
        TEmsEditFieldLabel              matlab.ui.control.Label
        TRmsEditField                   matlab.ui.control.NumericEditField
        TRmsEditFieldLabel              matlab.ui.control.Label
        TrajectoryEditField             matlab.ui.control.EditField
        TrajectoryEditFieldLabel        matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        FileEditField                   matlab.ui.control.EditField
        FileEditFieldLabel              matlab.ui.control.Label
        SelectButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PreprocessingTab                matlab.ui.container.Tab
        RunAllReconFittingButton        matlab.ui.control.Button
        SaveGifButton                   matlab.ui.control.Button
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        T2EditField                     matlab.ui.control.NumericEditField
        T2EditFieldLabel                matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        T1EditField                     matlab.ui.control.NumericEditField
        T1EditFieldLabel                matlab.ui.control.Label
        NewFOVroFOVEditField            matlab.ui.control.NumericEditField
        NewFOVroFOVEditFieldLabel       matlab.ui.control.Label
        PlayMovieButton                 matlab.ui.control.Button
        cLEditField                     matlab.ui.control.NumericEditField
        cLEditFieldLabel                matlab.ui.control.Label
        GeneratecurvePhiButton          matlab.ui.control.Button
        Lamp_9                          matlab.ui.control.Lamp
        Lamp_9Label                     matlab.ui.control.Label
        RankEditField                   matlab.ui.control.NumericEditField
        RankEditFieldLabel              matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        WWSlider                        matlab.ui.control.Slider
        WWSliderLabel                   matlab.ui.control.Label
        RotateButton                    matlab.ui.control.Button
        FlipLRButton                    matlab.ui.control.Button
        FlipUDButton                    matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        RunAllPreprocessingButton       matlab.ui.control.Button
        EnergyEditField                 matlab.ui.control.NumericEditField
        EnergyEditFieldLabel            matlab.ui.control.Label
        NewCoilEditField                matlab.ui.control.NumericEditField
        NewCoilEditFieldLabel           matlab.ui.control.Label
        SliceSlider_3                   matlab.ui.control.Slider
        SliceSlider_3Label              matlab.ui.control.Label
        TimeSlider                      matlab.ui.control.Slider
        TimeSliderLabel                 matlab.ui.control.Label
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_6Label                     matlab.ui.control.Label
        CoilCompressionButton           matlab.ui.control.Button
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_5Label                     matlab.ui.control.Label
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_4Label                     matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_2Label                     matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        RealtimeReconButton             matlab.ui.control.Button
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        ESPIRiTButton                   matlab.ui.control.RadioButton
        WalshButton                     matlab.ui.control.RadioButton
        CBDButton                       matlab.ui.control.RadioButton
        EstimateSensitivityMapButton    matlab.ui.control.Button
        SetupTrajectoryButton           matlab.ui.control.Button
        EddycurrentcompensationCheckBox  matlab.ui.control.CheckBox
        GradientdelaycorrectionCheckBox  matlab.ui.control.CheckBox
        secLabel                        matlab.ui.control.Label
        ScanLengthEditField             matlab.ui.control.EditField
        ScanLengthEditFieldLabel        matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        MotionCompensationTab           matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        RemoveBinsButton                matlab.ui.control.Button
        RunAllButton_3                  matlab.ui.control.Button
        CycleMinLabel_2                 matlab.ui.control.Label
        CycleMinLabel                   matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_4Label                matlab.ui.control.Label
        CardiacFreqEditField            matlab.ui.control.NumericEditField
        CardiacFreqEditFieldLabel       matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        RespFreqEditField               matlab.ui.control.NumericEditField
        RespFreqEditFieldLabel          matlab.ui.control.Label
        ManualROICheckBox_2             matlab.ui.control.CheckBox
        Lamp_14                         matlab.ui.control.Lamp
        Lamp_14Label                    matlab.ui.control.Label
        MoCoButton                      matlab.ui.control.Button
        BinSlider                       matlab.ui.control.Slider
        BinSliderLabel                  matlab.ui.control.Label
        RespBinLabel                    matlab.ui.control.Label
        PlayMovieButton_2               matlab.ui.control.Button
        SetasDiastoleButton             matlab.ui.control.Button
        FlipRidxButton                  matlab.ui.control.Button
        SliceSlider_4                   matlab.ui.control.Slider
        SliceSlider_4Label              matlab.ui.control.Label
        WWSlider_2                      matlab.ui.control.Slider
        WWSlider_2Label                 matlab.ui.control.Label
        CardiacBinsEditField            matlab.ui.control.NumericEditField
        CardiacBinsEditFieldLabel       matlab.ui.control.Label
        ManualROICheckBox               matlab.ui.control.CheckBox
        EditROIButton                   matlab.ui.control.Button
        DropDown_7                      matlab.ui.control.DropDown
        DropDown_7Label                 matlab.ui.control.Label
        Lamp_8                          matlab.ui.control.Lamp
        Lamp_8Label                     matlab.ui.control.Label
        Lamp_7                          matlab.ui.control.Lamp
        Lamp_7Label                     matlab.ui.control.Label
        CardiacBinningButton            matlab.ui.control.Button
        RespBinningButton               matlab.ui.control.Button
        RespiratoryBinsEditField        matlab.ui.control.NumericEditField
        RespiratoryBinsEditFieldLabel   matlab.ui.control.Label
        DropDown_3                      matlab.ui.control.DropDown
        DropDown_3Label                 matlab.ui.control.Label
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        UIAxes_12                       matlab.ui.control.UIAxes
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        TensorTab                       matlab.ui.container.Tab
        SmallerlambdamoredetailsLargerlambdasmootherimageLabel  matlab.ui.control.Label
        SaveGifButton_3                 matlab.ui.control.Button
        RunAllButton_2                  matlab.ui.control.Button
        BlackBloodButton                matlab.ui.control.StateButton
        UseToeplitzCheckBox             matlab.ui.control.CheckBox
        RankEditField_2                 matlab.ui.control.NumericEditField
        RankEditField_2Label            matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        RespLabel                       matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.NumericEditField
        lambdaLabel                     matlab.ui.control.Label
        AutolambdaCheckBox_2            matlab.ui.control.CheckBox
        DropDown_5                      matlab.ui.control.DropDown
        WWSlider_3                      matlab.ui.control.Slider
        WWSlider_3Label                 matlab.ui.control.Label
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_4Label                 matlab.ui.control.Label
        SliceSlider_5                   matlab.ui.control.Slider
        SliceSlider_5Label              matlab.ui.control.Label
        TimeSlider_2                    matlab.ui.control.Slider
        TimeSlider_2Label               matlab.ui.control.Label
        PlayMovieButton_3               matlab.ui.control.Button
        smsEditField_2                  matlab.ui.control.NumericEditField
        SetupTensorParametersButton     matlab.ui.control.Button
        smsEditField                    matlab.ui.control.NumericEditField
        smsLabel                        matlab.ui.control.Label
        lrEditField                     matlab.ui.control.NumericEditField
        lrLabel                         matlab.ui.control.Label
        Lamp_13                         matlab.ui.control.Lamp
        Lamp_13Label                    matlab.ui.control.Label
        Lamp_12                         matlab.ui.control.Lamp
        Lamp_12Label                    matlab.ui.control.Label
        Lamp_10                         matlab.ui.control.Lamp
        Lamp_10Label                    matlab.ui.control.Label
        CardiacLabel                    matlab.ui.control.Label
        TensorSubspaceButton            matlab.ui.control.Button
        DimensionReductionButtonGroup   matlab.ui.container.ButtonGroup
        cLButton                        matlab.ui.control.RadioButton
        navButton                       matlab.ui.control.RadioButton
        NoneButton                      matlab.ui.control.RadioButton
        TVReconButton                   matlab.ui.control.Button
        AutolambdaCheckBox              matlab.ui.control.CheckBox
        UIAxes_10                       matlab.ui.control.UIAxes
        FittingTab                      matlab.ui.container.Tab
        LoadFitParamsButton             matlab.ui.control.Button
        stepfittingCheckBox             matlab.ui.control.CheckBox
        DropDown_6                      matlab.ui.control.DropDown
        DropDown_6Label                 matlab.ui.control.Label
        SliceSlider_6                   matlab.ui.control.Slider
        SliceSlider_6Label              matlab.ui.control.Label
        PhaseSlider                     matlab.ui.control.Slider
        PhaseSliderLabel                matlab.ui.control.Label
        PlayMovieButton_4               matlab.ui.control.Button
        SaveGifButton_4                 matlab.ui.control.Button
        Lamp_15                         matlab.ui.control.Lamp
        Lamp_15Label                    matlab.ui.control.Label
        B1betaEditField                 matlab.ui.control.NumericEditField
        B1betaEditFieldLabel            matlab.ui.control.Label
        InvEfficiencyEditField          matlab.ui.control.NumericEditField
        InvEfficiencyEditFieldLabel     matlab.ui.control.Label
        T2EditField_2                   matlab.ui.control.NumericEditField
        T2EditField_2Label              matlab.ui.control.Label
        T1EditField_2                   matlab.ui.control.NumericEditField
        T1EditField_2Label              matlab.ui.control.Label
        SaveFittingResultButton         matlab.ui.control.Button
        CheckFittingResultButton        matlab.ui.control.Button
        InitialValueMinMaxLabel         matlab.ui.control.Label
        StartFittingButton              matlab.ui.control.Button
        FitParametersButtonGroup        matlab.ui.container.ButtonGroup
        SingleInvEfficiencyButton       matlab.ui.control.RadioButton
        ConstantbetaButton              matlab.ui.control.RadioButton
        AllButton                       matlab.ui.control.RadioButton
        EditField17                     matlab.ui.control.NumericEditField
        EditField16                     matlab.ui.control.NumericEditField
        EditField14                     matlab.ui.control.NumericEditField
        EditField13                     matlab.ui.control.NumericEditField
        EditField11                     matlab.ui.control.NumericEditField
        EditField10                     matlab.ui.control.NumericEditField
        EditField8                      matlab.ui.control.NumericEditField
        EditField7                      matlab.ui.control.NumericEditField
        TotalFramesEditField            matlab.ui.control.NumericEditField
        TotalFramesEditFieldLabel       matlab.ui.control.Label
        SlicesEditField                 matlab.ui.control.EditField
        SlicesEditFieldLabel            matlab.ui.control.Label
        CardiacPhaseEditField           matlab.ui.control.EditField
        CardiacPhaseEditFieldLabel      matlab.ui.control.Label
        RespPhaseEditField              matlab.ui.control.EditField
        RespPhaseEditFieldLabel         matlab.ui.control.Label
        SkipframeEditField              matlab.ui.control.NumericEditField
        SkipframeEditFieldLabel         matlab.ui.control.Label
        FittingstartatframeEditField    matlab.ui.control.NumericEditField
        FittingstartatframeEditFieldLabel  matlab.ui.control.Label
        MaskHigherlevellargermaskButtonGroup  matlab.ui.container.ButtonGroup
        BETButton                       matlab.ui.control.RadioButton
        NoMaskButton                    matlab.ui.control.RadioButton
        Level3Button                    matlab.ui.control.RadioButton
        Level2Button                    matlab.ui.control.RadioButton
        Level1Button                    matlab.ui.control.RadioButton
        UIAxes_13                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        TwixObj             % twix object loaded from Siemens rawdata
        Params              % measurement parameters
        ReconOptions        % recon settings
        DataArray           % data arrays
        TemporalBasis       % temporal bases
        SpatialCoeff        % spatial coefficients
        FitParams           % data and parameters for parametric fitting
        FitResult           % fitting result
        TempStructTensor    % temporary parameters for tensor recon
        UIDisplay           % Store display items
        ReconstructedImages % reconstructed images are stored here
    end

    properties (Access = private)
    end

    methods (Access = private)
    
        function updateTextFieldFromStruct(app)
            [~,fileName,fileExt] = fileparts(app.Params.fidString);
            app.FileEditField.Value = [fileName fileExt];
            app.ScanLengthEditField.Value = num2str(app.ReconOptions.totalTime, '%.1f');
            app.TrajectoryEditField.Value = app.Params.Trajectory;
            app.ScanTypeEditField.Value = app.Params.ScanType;
            if app.Params.numIRns > 0
                app.IREditField.Value = 'on';
            else
                app.IREditField.Value = 'off';
            end
            if app.Params.numT2prep > 0
                app.T2IRmsEditField.Value = num2str(app.Params.T2prepDuration);
            end
            if app.Params.numT1rhoPrep > 0
                app.T1rhoprepmsEditField.Value = num2str(app.Params.T1rhoDuration);
            end
            app.FlipAngleEditField.Value = num2str(app.Params.flipAngleArray);
            app.TRmsEditField.Value = app.Params.alTR_seconds*1000;
            app.TEmsEditField.Value = app.Params.alTE_seconds*1000;
            app.EchospacingmsEditField.Value = app.Params.lEchoSpacing*1000;
            app.SGBlockEditField.Value = app.Params.SGBlock;
            app.LinesperShotEditField.Value = app.Params.linesPerShot;
            app.TotalFramesEditField.Value  = app.Params.linesPerShot;
            app.MBFactorEditField.Value = app.Params.MBfactor;
            app.NAcqEditField.Value = app.Params.Ntpoint;
            app.NkxEditField.Value = app.Params.Nkx;
            app.NcoilsEditField.Value = app.Params.Ncoils;
            app.FOVrommEditField.Value = app.Params.dReadoutFOV_mm/app.NewFOVroFOVEditField.Value;
            app.FOVpemmEditField.Value = app.Params.dPhaseFOV_mm;
            app.SliceThicknessmmEditField.Value = app.Params.dThickness_mm;
            app.NxEditField.Value = app.Params.Nxdisp;
            app.NyEditField.Value = app.Params.Nydisp;
            app.NzEditField.Value = app.Params.Nzdisp;
            drawnow();
        end
        
        function updateStatus(app, msg)
            app.StatusEditField.Value = msg; 
            app.StatusEditField.BackgroundColor = [0.94,0.94,0.94];
            drawnow();
        end
        
        function updateErrorStatus(app, msg)
            app.StatusEditField.Value = msg; 
            app.StatusEditField.BackgroundColor = [1.0,0.0,0.0];
            drawnow();
        end
        
        function imgout = dispim(app,img,slice,tpoint)
            Nxdisp = app.Params.Nxdisp;
            Nydisp = app.Params.Nydisp;
            Nx = size(img,2);
            Ny = size(img,1); 
            Nxshift = floor(Nx/2) - floor(Nxdisp/2);
            Nyshift = floor(Ny/2) - floor(Nydisp/2);
            imgout = img(Nyshift+(1:Nydisp),Nxshift+(1:Nxdisp),slice,tpoint);
        end
        
        function updatePreProcAxesImage(app)
            %imgDisp = app.UIDisplay.currentDisplayPreproc;
            imgDisp = prepImageForAxes(app,abs(app.UIDisplay.currentDisplayPreproc));
            imgDisp = imgDisp/abs(max(imgDisp(:))); % Normalize image intensity
            app.UIDisplay.currentDisplayPreproc = imgDisp;
            
            % reset sliders
            app.SliceSlider_3.Limits(2) = max(size(imgDisp,3),2); 
            app.SliceSlider_3.Value = floor(size(imgDisp,3)/2) + 1;
            displayNz = app.SliceSlider_3.Value;
            app.TimeSlider.Limits(2) = max(size(imgDisp,4),2); 
            app.TimeSlider.Value = 1;
            displayNt = app.TimeSlider.Value;
            scalar = app.WWSlider.Value;
            
            % crop image and display
            imgDisp = dispim(app,imgDisp,displayNz,displayNt);
            I = imshow(imgDisp/scalar,[0 1],'Parent',app.UIAxes,'XData',[1 app.UIAxes.Position(3)],'YData',[1 app.UIAxes.Position(4)]);
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end
        
        function updatePreProcAxes(app)
            displayNz = app.SliceSlider_3.Value;
            displayNt = app.TimeSlider.Value;
            scalar = app.WWSlider.Value;
            imgDisp = dispim(app,app.UIDisplay.currentDisplayPreproc,displayNz,displayNt);
            I = imshow(imgDisp/scalar,[0 1],'Parent',app.UIAxes,'XData',[1 app.UIAxes.Position(3)],'YData',[1 app.UIAxes.Position(4)]);
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end
        
        function calcUIPlotPhi(app)
            Ndisp = min(ceil(app.Params.linesPerShot*10.01/app.Params.SGBlock), size(app.TemporalBasis.Phi_rt_small,2));  % show 10 shots
            Nskip = floor(1/5/(app.Params.lEchoSpacing*app.Params.SGBlock));                                   % make frequence spectrum range -2.5Hz~2.5Hz
            app.UIDisplay.Phi = realify(app.TemporalBasis.Phi_rt_small(:,1:Ndisp), 'rows');
            app.UIDisplay.PhiFFT = abs(fftshift(fft(app.TemporalBasis.Phi_rt_small(:,1:Nskip:end),[],2),2));
        end
        
        
        function updateBinningAxesImage(app)
            imgDisp = prepImageForAxes(app,abs(app.UIDisplay.currentBinning));
            imgDisp = imgDisp/abs(max(imgDisp(:))); % Normalize image intensity
            app.UIDisplay.currentBinning = imgDisp;
            
            % reset sliders
            app.SliceSlider_4.Limits(2) = max(size(imgDisp,3),2); 
            app.SliceSlider_4.Value = floor(size(imgDisp,3)/2) + 1;
            app.SliceSlider_4Label.Text = [app.UIDisplay.ylabel '1'];
            displayNz = app.SliceSlider_4.Value;
            app.BinSlider.Limits(2) = max(size(imgDisp,4),2); 
            app.BinSlider.Value = 1;
            app.BinSliderLabel.Text = [app.UIDisplay.xlabelBin '1'];
            displayNt = app.BinSlider.Value;
            scalar = app.WWSlider_2.Value;
            
            % crop image and display
            imgDisp = dispim(app,imgDisp,displayNz,displayNt);
            I = imshow(imgDisp/scalar,[0 1],'Parent',app.UIAxes_7,'XData',[1 app.UIAxes_7.Position(3)],'YData',[1 app.UIAxes_7.Position(4)]);
            app.UIAxes_7.XLim = [0 I.XData(2)];
            app.UIAxes_7.YLim = [0 I.YData(2)];
        end
        
        function updateBinningAxes(app)
            displayNz = app.SliceSlider_4.Value;
            displayNt = app.BinSlider.Value;
            scalar = app.WWSlider_2.Value;
            imgDisp = dispim(app,app.UIDisplay.currentBinning,displayNz,displayNt);
            I = imshow(imgDisp/scalar,[0 1],'Parent',app.UIAxes_7,'XData',[1 app.UIAxes_7.Position(3)],'YData',[1 app.UIAxes_7.Position(4)]);
            app.UIAxes_7.XLim = [0 I.XData(2)];
            app.UIAxes_7.YLim = [0 I.YData(2)];
        end
        
        function updateTensorAxesImage(app)
            imgDisp = prepImageForAxes(app,app.UIDisplay.currentTensor);
            imgDisp = imgDisp/abs(max(imgDisp(:))); % Normalize image intensity
            app.UIDisplay.currentTensor = imgDisp;
            
            % reset sliders
            app.SliceSlider_5.Limits(2) = max(size(imgDisp,3),2); 
            app.SliceSlider_5.Value = floor(size(imgDisp,3)/2) + 1;
            app.SliceSlider_5Label.Text = [app.UIDisplay.ylabel '1'];
            displayNz = app.SliceSlider_5.Value;
            app.TimeSlider_2.Limits(2) = max(size(imgDisp,4),2); 
            app.TimeSlider_2.Value = 1;
            app.TimeSlider_2Label.Text = [app.UIDisplay.xlabelTensor '1'];
            displayNt = app.TimeSlider_2.Value;
            scalar = app.WWSlider_3.Value;
            
            % crop image and display
            imgDisp = dispim(app,imgDisp,displayNz,displayNt);
            I = imshow(imgDisp/scalar,[0 1],'Parent',app.UIAxes_10,'XData',[1 app.UIAxes_10.Position(3)],'YData',[1 app.UIAxes_10.Position(4)]);
            app.UIAxes_10.XLim = [0 I.XData(2)];
            app.UIAxes_10.YLim = [0 I.YData(2)];
        end

        function updateTensorAxes(app)
            displayNz = app.SliceSlider_5.Value;
            displayNt = app.TimeSlider_2.Value;
            scalar = app.WWSlider_3.Value;
            imgDisp = dispim(app,app.UIDisplay.currentTensor,displayNz,displayNt);
            I = imshow(imgDisp/scalar,[0 1],'Parent',app.UIAxes_10,'XData',[1 app.UIAxes_10.Position(3)],'YData',[1 app.UIAxes_10.Position(4)]);
            app.UIAxes_10.XLim = [0 I.XData(2)];
            app.UIAxes_10.YLim = [0 I.YData(2)];
        end
        
        function updateMapAxesImage(app)
            % reset sliders
            app.SliceSlider_6.Limits(2) = max(size(app.UIDisplay.currentMap,3),2); 
            app.SliceSlider_6.Value = floor(size(app.UIDisplay.currentMap,3)/2) + 1;
            app.SliceSlider_6Label.Text = 'Slice 1';
            displayNz = app.SliceSlider_6.Value;
            app.PhaseSlider.Limits(2) = max(size(app.UIDisplay.currentMap,4),2); 
            app.PhaseSlider.Value = 1;
            app.PhaseSliderLabel.Text = 'Phase 1';
            displayNt = app.PhaseSlider.Value;
            
            % display image
            imgDisp = (prepImageForAxes(app,app.UIDisplay.currentMap(:,:,displayNz,displayNt)));
            imgDisp_color = ind2rgb(round(255*imgDisp),warmmetal(256));
            I = imshow(imgDisp_color,'Parent',app.UIAxes_13,'XData',[1 app.UIAxes_13.Position(3)],'YData',[1 app.UIAxes_13.Position(4)]);
            app.UIAxes_13.XLim = [0 I.XData(2)];
            app.UIAxes_13.YLim = [0 I.YData(2)];
        end
        
        function updateMapAxes(app)
            displayNz = app.SliceSlider_6.Value;
            displayNt = app.PhaseSlider.Value;
            imgDisp   = (app.UIDisplay.currentMap(:,:,displayNz,displayNt));
            imgDisp_color = ind2rgb(round(255*imgDisp),warmmetal(256));
            I = imshow(imgDisp_color,'Parent',app.UIAxes_13,'XData',[1 app.UIAxes_13.Position(3)],'YData',[1 app.UIAxes_13.Position(4)]);
            app.UIAxes_13.XLim = [0 I.XData(2)];
            app.UIAxes_13.YLim = [0 I.YData(2)];
        end
        
        function imgDisp = prepImageForAxes(app,imgDisp)        
            % Flip/rotate image
            if dot(app.UIDisplay.matFlipRot(:,1),[1 0]) == 1
                if dot(app.UIDisplay.matFlipRot(:,2),[0 1]) == -1
                    imgDisp = fliplr(imgDisp);
                end
            elseif dot(app.UIDisplay.matFlipRot(:,1),[1 0]) == -1
                imgDisp = flipud(imgDisp);
                if dot(app.UIDisplay.matFlipRot(:,2),[0 1]) == -1
                    imgDisp = fliplr(imgDisp);
                end
            elseif dot(app.UIDisplay.matFlipRot(:,1),[0 1]) == 1
                imgDisp = imrotate(imgDisp,90);
                if dot(app.UIDisplay.matFlipRot(:,2),[1 0]) == 1
                    imgDisp = flipud(imgDisp);
                end
            elseif dot(app.UIDisplay.matFlipRot(:,1),[0 1]) == -1
                imgDisp = imrotate(imgDisp,-90);
                if dot(app.UIDisplay.matFlipRot(:,2),[1 0]) == -1
                    imgDisp = flipud(imgDisp);
                end
            end
        end
        
        function imgDisp = revertImageOrient(app,imgDisp)
            % Flip/rotate image
            if dot(app.UIDisplay.matFlipRot(:,1),[1 0]) == 1
                if dot(app.UIDisplay.matFlipRot(:,2),[0 1]) == -1
                    imgDisp = fliplr(imgDisp);
                end
            elseif dot(app.UIDisplay.matFlipRot(:,1),[1 0]) == -1
                imgDisp = flipud(imgDisp);
                if dot(app.UIDisplay.matFlipRot(:,2),[0 1]) == -1
                    imgDisp = fliplr(imgDisp);
                end
            elseif dot(app.UIDisplay.matFlipRot(:,1),[0 1]) == 1
                imgDisp = imrotate(imgDisp,-90);
                if dot(app.UIDisplay.matFlipRot(:,2),[1 0]) == 1
                    imgDisp = flipud(imgDisp);
                end
            elseif dot(app.UIDisplay.matFlipRot(:,1),[0 1]) == -1
                imgDisp = imrotate(imgDisp,90);
                if dot(app.UIDisplay.matFlipRot(:,2),[1 0]) == -1
                    imgDisp = flipud(imgDisp);
                end
            end
        end
        
        function initUIElements(app)
            app.SetupTrajectoryButton.Enable = 'off';
            app.EstimateSensitivityMapButton.Enable = 'off';
            app.RealtimeReconButton.Enable = 'off';
            app.CoilCompressionButton.Enable = 'off';
            app.SliceSlider_3.Enable = 'off';
            app.RunAllPreprocessingButton.Enable = 'off';
            app.DropDownLabel.Enable = 'off';
            app.DropDown.Enable = 'off';
            app.FlipUDButton.Enable = 'off';
            app.FlipLRButton.Enable = 'off';
            app.RotateButton.Enable = 'off';
            app.WWSlider.Enable = 'off';
            app.GeneratecurvePhiButton.Enable = 'off';
            app.PlayMovieButton.Enable = 'off';
            app.DropDown_2Label.Enable = 'off';
            app.DropDown_2.Enable = 'off';
            app.DropDown_3Label.Enable = 'off';
            app.DropDown_3.Enable = 'off';
            app.RespBinningButton.Enable = 'off';
            app.CardiacBinningButton.Enable = 'off';
            app.EditROIButton.Enable = 'off';
            app.WWSlider_2.Enable = 'off';
            app.SliceSlider_4.Enable = 'off';
            app.FlipRidxButton.Enable = 'off';
            app.SetasDiastoleButton.Enable = 'off';
            app.PlayMovieButton_2.Enable = 'off';
            app.BinSlider.Enable = 'off';
            app.TVReconButton.Enable = 'off';
            app.TensorSubspaceButton.Enable = 'off';
            app.SetupTensorParametersButton.Enable = 'off';
            app.PlayMovieButton_3.Enable = 'off';
            app.TimeSlider_2.Enable = 'off';
            app.SliceSlider_5.Enable = 'off';
            app.DropDown_4Label.Enable = 'off';
            app.DropDown_4.Enable = 'off';
            app.WWSlider_3.Enable = 'off';
            app.DropDown_5.Enable = 'off';
            app.LoadReconSettingButton.Enable = 'off';
            app.SaveWorkspaceButton.Enable = 'off';
            app.ExportDICOMButton.Enable = 'off';
        end
        
        function updateSuccessStatus(app, msg)
            app.StatusEditField.Value = msg; 
            app.StatusEditField.BackgroundColor = [0, 1.0, 0];
            drawnow();
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mainpath = fileparts(mfilename('fullpath'));
            addpath(genpath(fullfile(mainpath,'supporting','recon'))); %Supporting reconstruction scripts
            addpath(genpath(fullfile(mainpath,'supporting','utils'))); %Supporting utilities
            addpath(genpath(fullfile(mainpath,'supporting','colormaps')));

            %  -- Third-party paths are below:
            
            % Siemens rawdata import code. Included.
            addpath(fullfile(mainpath,'supporting','mapVBVD'));
            
            % NYU GPU NUFFT code. Not included. You will have to download, compile, and manage your own copy
            %addpath(genpath('~/gpuNUFFT'));
            try
                if gpuDeviceCount && isfolder(fullfile(mainpath,'supporting','nufft','gpuNUFFT','gpuNUFFT'))
                    addpath(fullfile(mainpath,'supporting','nufft','gpuNUFFT','gpuNUFFT'));
                    app.UseGPUCheckBox.Enable = 'on';
                    fprintf('-- gpuNUFFT path added.\n');
                else
                    app.UseGPUCheckBox.Value  = false;
                    app.UseGPUCheckBox.Enable = 'off';
                end
            catch
                app.UseGPUCheckBox.Value  = false;
                app.UseGPUCheckBox.Enable = 'off';
            end
            
            % finufft
            if exist(fullfile(mainpath,'supporting','nufft','finufft','matlab'),'dir')
                addpath(fullfile(mainpath,'supporting','nufft','finufft','matlab')); 
                app.ReconOptions.flagUsefinufft = 1;
                fprintf('-- finufft path added.\n');
            else
                app.ReconOptions.flagUsefinufft = 0;
            end
            
            % MIRT
            if exist(fullfile(mainpath,'supporting','nufft','irt'), 'dir')
                currentdir = pwd;
                irtdir = fullfile(mainpath,'supporting','nufft','irt');
                cd(irtdir); setup; cd(currentdir);
            end
            
            % BART
            if exist(fullfile(mainpath,'supporting','nufft','bart','matlab'),'dir')
                addpath(fullfile(mainpath,'supporting','nufft','bart','matlab'));   
                setenv('TOOLBOX_PATH', fullfile(mainpath,'supporting','nufft','bart'));
                if app.ReconOptions.flagUsefinufft
                    app.ReconOptions.flagUseBart = 0;
                else
                    app.ReconOptions.flagUseBart = 1;
                end
                fprintf('-- BART path added.\n');
            else
                app.ReconOptions.flagUseBart = 0;
            end
            
            app.ReconOptions.flagCommandLine = false;
            app.ReconOptions.mainpath = mainpath;
            
            isValid = testExternLicense;
            if ~isValid
                [~,~,tempID] = loadLicenseFile;
                tempID = erase(tempID,'_Offline');
                genTempLicenseFile(tempID);
                fprintf('done.\n')
            end
            
            [licenseNr,flagOffline,app.ReconOptions.licenseID] = loadLicenseFile;
            if flagOffline
                licenseNr  = licenseNr/sum(double('multitasking'));
                datestr    = char(datetime(licenseNr(end),'ConvertFrom','datenum','Format','MM/dd/yyyy'));
                Ndays      = licenseNr(end) - datenum(date);
                if Ndays < 0
                    IDtag = ['License ID: ' app.ReconOptions.licenseID(1:end-8) ' - EXPIRED ' datestr];
                else
                    IDtag = ['License ID: ' app.ReconOptions.licenseID(1:end-8) ' - ' datestr];
                end
            else
                IDtag = ['License ID: ' app.ReconOptions.licenseID ' - Online'];
            end
            app.LicenseIDLabel.Text = IDtag;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            updateStatus(app, ' Select data');
            [app.Params, status, msg] = selectData;
            
            try     
                [~,fileName,fileExt] = fileparts(app.Params.fidString);
                app.FileEditField.Value = [fileName fileExt];
                app.Params.mainpath = fileparts(mfilename('fullpath'));
                app.MultitaskingReconUIFigure.Visible = 'off';
                app.MultitaskingReconUIFigure.Visible = 'on';
                
                initUIElements(app);
                
                if status
                    updateErrorStatus(app, msg);
                end
                if status < 2
                    cd(fileparts(app.Params.filePath));
                    
                    updateStatus(app, ' Loading data header ......');
                    [app.Params, app.ReconOptions, app.TwixObj, app.DataArray, app.TemporalBasis, app.SpatialCoeff, app.FitParams,status,msg] = loadHeader(app.Params, app.ReconOptions);
                    if status == 2
                        updateErrorStatus(app, msg);
                    else
                        updateSuccessStatus(app, msg);
                    end
                    
                    app.Lamp.Color    = [0.8 0.8 0.8];
                    app.Lamp_2.Color  = [0.8 0.8 0.8];
                    app.Lamp_4.Color  = [0.8 0.8 0.8];
                    app.Lamp_5.Color  = [0.8 0.8 0.8];
                    app.Lamp_6.Color  = [0.8 0.8 0.8];
                    app.Lamp_7.Color  = [0.8 0.8 0.8];
                    app.Lamp_8.Color  = [0.8 0.8 0.8];
                    app.Lamp_9.Color  = [0.8 0.8 0.8];
                    app.Lamp_10.Color = [0.8 0.8 0.8];
                    app.Lamp_12.Color = [0.8 0.8 0.8];
                    app.Lamp_13.Color = [0.8 0.8 0.8];
                    app.Lamp_14.Color = [0.8 0.8 0.8];
                    app.Lamp_15.Color = [0.8 0.8 0.8];
                    
                    app.TrajectoryEditField.Value = app.Params.Trajectory;
                    if strcmp(app.Params.Trajectory,'Cartesian')
                        app.UseToeplitzCheckBox.Value = 0;
                        app.UseToeplitzCheckBox.Enable = 'off';
                    else
                        app.UseToeplitzCheckBox.Enable = 'on';
                    end
                    if app.Params.numIRns > 0
                        app.IREditField.Value = 'on';
                    else
                        app.IREditField.Value = 'off';
                    end
                    if app.Params.numT2prep > 0
                        app.T2IRmsEditField.Value = num2str(app.Params.T2prepDuration);
                    else
                        app.T2IRmsEditField.Value = '--';
                    end
                    if app.Params.numT1rhoPrep > 0
                        app.T1rhoprepmsEditField.Value = num2str(app.Params.T1rhoDuration);
                    else
                        app.T1rhoprepmsEditField.Value = '--';
                    end
                    app.FlipAngleEditField.Value = num2str(app.Params.flipAngleArray);
                    app.ScanTypeEditField.Value = app.Params.ScanType;
                    app.TRmsEditField.Value = app.Params.alTR_seconds*1000;
                    app.TEmsEditField.Value = app.Params.alTE_seconds*1000;
                    app.EchospacingmsEditField.Value = app.Params.lEchoSpacing*1000;
                    app.SGBlockEditField.Value = app.Params.SGBlock;
                    if isempty(app.Params.linesPerShot)
                        app.LinesperShotEditField.Value = app.Params.lSegments;
                    elseif app.Params.linesPerShot == 0
                        app.LinesperShotEditField.Value = app.Params.lSegments;
                    else
                        app.LinesperShotEditField.Value = app.Params.linesPerShot;
                    end
                    app.Params.linesPerShot = app.LinesperShotEditField.Value;
                    app.TotalFramesEditField.Value = app.Params.linesPerShot;
                    
                    if isempty(app.Params.MBfactor) || strcmp(app.TwixObj.hdr.Dicom.tMRAcquisitionType, '3D')
                        app.Params.MBfactor = 1;
                        app.MBFactorEditField.Value = 1;
                    else
                        app.MBFactorEditField.Value = app.Params.MBfactor;
                    end
                
                    app.NAcqEditField.Value = app.TwixObj.image.NAcq;
                    app.NEchoEditField.Value = app.TwixObj.image.NEco;
                    app.NkxEditField.Value = app.TwixObj.image.NCol;
                    if app.Params.isCartesian
                        app.NkyEditField.Value = app.TwixObj.image.NLin;
                        app.NkyEditFieldLabel.Text = 'Nky';
                    else
                        app.NkyEditField.Value = app.Params.Ntrajs;
                        app.NkyEditFieldLabel.Text = 'Ntrajs';
                    end
                    app.NkzEditField.Value = app.Params.Nz;
                    app.NcoilsEditField.Value = app.TwixObj.image.NCha;
                    app.FOVrommEditField.Value = app.Params.dReadoutFOV_mm;
                    app.FOVpemmEditField.Value = app.Params.dPhaseFOV_mm;
                    app.SliceThicknessmmEditField.Value = app.Params.dThickness_mm;
                    app.NxEditField.Value = app.Params.lBaseResolution;
                    if app.Params.isCartesian
                        app.NyEditField.Value = app.Params.NImageLines;
                    else
                        app.NyEditField.Value = app.Params.lBaseResolution;
                    end
                    app.NzEditField.Value = app.Params.Nzdisp;
                    app.SlicesEditField.Value = ['1:' num2str(app.NzEditField.Value)];

                    app.SaveWorkspaceButton.Enable = 'on';
                    app.RunAllReconFittingButton.Enable = 'on';
                    app.RunAllPreprocessingButton.Enable = 'on';
                    app.LoadDataButton.Enable = 'on';
                    app.secLabel.Enable = 'on';
                    app.ScanLengthEditField.Enable = 'on';
                    if isfield(app.DataArray, 'kspaceData')
                        app.Lamp.Color = [0 1 0];
                        app.ScanLengthEditField.Value  = num2str(app.ReconOptions.totalTime, '%.1f');
                        if app.DataArray.flagIsTrajectorySet && app.DataArray.flagIsPrewhitened
                            app.Lamp_2.Color = [0 1 0];
                            if app.DataArray.flagIsCompressed
                                app.Lamp_4.Color = [0 1 0]; 
                            end
                        end
                        if isfield(app.DataArray, 'SEs')
                            app.Lamp_5.Color = [0 1 0]; 
                        end
                        if isfield(app.DataArray, 'reconRealtime')
                            app.Lamp_6.Color = [0 1 0]; 
                        end
                    else
                        app.Lamp.Color = [0.8 0.8 0.8];
                        app.ReconOptions.totalTime = floor(app.Params.alTR_seconds*app.TwixObj.image.NAcq/app.Params.linesPerShot);
                        app.ScanLengthEditField.Value  = num2str(app.ReconOptions.totalTime);
                    end
                    if strcmp(app.Params.Trajectory,'Cartesian')
                        app.UseGPUCheckBox.Value  = false;
                        app.UseGPUCheckBox.Enable = 'off';
                    else
                        if isfield(app.ReconOptions, 'flagUseGPU')
                            app.UseGPUCheckBox.Value = logical(app.ReconOptions.flagUseGPU);
                        else
                            app.ReconOptions.flagUseGPU = app.UseGPUCheckBox.Value;
                        end
                        if gpuDeviceCount && isfolder(fullfile(app.Params.mainpath,'supporting','nufft','gpuNUFFT'))
                            app.UseGPUCheckBox.Enable = 'on';
                        else
                            app.UseGPUCheckBox.Enable = 'off';
                            app.UseGPUCheckBox.Value  = false;
                            app.ReconOptions.flagUseGPU = false;
                        end
                    end
                    if strcmp(app.Params.Trajectory,'Radial')
                        app.GradientdelaycorrectionCheckBox.Enable = 'on';
                        app.GradientdelaycorrectionCheckBox.Value = 1;
                        app.EddycurrentcompensationCheckBox.Enable   = 'on';
                        app.EddycurrentcompensationCheckBox.Value = 1;
                    else
                        app.GradientdelaycorrectionCheckBox.Enable = 'off';
                        app.GradientdelaycorrectionCheckBox.Value = 0;
                        app.EddycurrentcompensationCheckBox.Enable   = 'off';
                        app.EddycurrentcompensationCheckBox.Value = 0;
                    end
                    app.NewFOVroFOVEditField.Enable  = 'on';  
                    app.NewFOVroFOVEditFieldLabel.Enable = 'on';
                    if isfield(app.TemporalBasis,'curvePhi')
                        app.RespBinningButton.Enable = 'on';
                        app.RunAllButton_3.Enable = 'on';
                        app.Lamp_9.Color = [0 1 0];
                    end
                    if isfield(app.Params, 'Ridx') && isfield(app.Params, 'Hidx')
                        app.Lamp_7.Color = [0 1 0];
                        app.Lamp_8.Color = [0 1 0];
                    else
                        app.Lamp_7.Color = [0.8 0.8 0.8];
                        app.Lamp_8.Color = [0.8 0.8 0.8];
                        app.MoCoButton.Enable = 'off';
                        app.RemoveBinsButton.Enable = 'off';
                    end
                    if ~isfield(app.UIDisplay,'matFlipRot')
                        app.UIDisplay.matFlipRot = eye(2);
                        app.UIDisplay.xlabel = 'Time ';
                        app.UIDisplay.ylabel = 'Slice ';
                    end
                    app.IREditField.Enable = 'on';
                    app.T2IRmsEditField.Enable = 'on';
                    app.T1rhoprepmsEditField.Enable = 'on';
                    app.FlipAngleEditField.Enable = 'on';
                    app.SGBlockEditField.Enable = 'on';
                    app.LinesperShotEditField.Enable = 'on';
                    app.MBFactorEditField.Enable = 'on';
                    
%                     if strcmpi(app.Params.BodyPartExamined,'BRAIN')
%                         app.BETButton.Value = 1;
%                     else
                        app.Level1Button.Value = 1;
%                     end

                    switch app.Params.ScanType
                        case 'IR'
                            app.cLEditField.Value = 5;
                            app.ConstantbetaButton.Value = false;
                            app.EditField16.Value  = app.B1betaEditField.Value;
                            app.EditField17.Value  = app.B1betaEditField.Value;
                        case 'T2IR'
                            app.cLEditField.Value = 8;
                            app.SingleInvEfficiencyButton.Value = true;
                            app.EditField16.Value  = 0.1;
                            app.EditField17.Value  = 1.0;
                        case 'T2IR_VFA'
                            app.cLEditField.Value = 7;
                            app.SingleInvEfficiencyButton.Value = true;
                            app.EditField16.Value  = 0.1;
                            app.EditField17.Value  = 1.0;
                        case 'SR'
                            app.cLEditField.Value = 3;
                    end
                end
            catch errormsg
                fprintf(2,'Error loading data header: %s\n', errormsg.message);
                updateErrorStatus(app, 'Invalid selection.');
            end
            app.ReconOptions.flagCommandLine = false;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.Lamp.Color = [1 0 0];
            app.Lamp_2.Color = [0.8 0.8 0.8];
            app.Lamp_4.Color = [0.8 0.8 0.8];
            app.Lamp_5.Color = [0.8 0.8 0.8];
            app.Lamp_6.Color = [0.8 0.8 0.8];
            app.Lamp_9.Color = [0.8 0.8 0.8];
            if isfinite(str2double(app.ScanLengthEditField.Value))
                app.ReconOptions.totalTime = str2double(app.ScanLengthEditField.Value);
            else
                app.ReconOptions.totalTime = Inf;
            end
            
            updateStatus(app, ' Loading k-space data ......');
            [app.Params, app.ReconOptions, app.DataArray] = loadData(app.Params, app.ReconOptions, app.TwixObj, app.DataArray);
            
            updateTextFieldFromStruct(app);
            if strcmp(app.Params.ScanType, 'T2IR')
                app.cLEditField.Value = 8;
                app.T2EditField.Enable = 'on';
                app.EditField_2.Enable = 'on';
                app.EditField16.Value  = 0.1;
                app.EditField17.Value  = 1.0;
                app.SingleInvEfficiencyButton.Value = true;
                app.stepfittingCheckBox.Value = false;
                app.stepfittingCheckBox.Enable = 'off';
            elseif strcmp(app.Params.ScanType, 'T2IR_VFA')
                app.cLEditField.Value = 8;
                app.T2EditField.Enable = 'on';
                app.EditField_2.Enable = 'on';
                app.EditField16.Value  = 0.1;
                app.EditField17.Value  = 1.0;
                app.SingleInvEfficiencyButton.Value = true;
                app.stepfittingCheckBox.Enable = 'on';
            elseif strcmp(app.Params.ScanType, 'SR')
                app.cLEditField.Value = 3;
                app.T2EditField.Enable = 'off';
                app.EditField_2.Enable = 'off';
                app.ConstantbetaButton.Value = true;
            elseif strcmp(app.Params.ScanType, 'IR')
                app.cLEditField.Value = 5;
                app.T2EditField.Enable = 'off';
                app.EditField_2.Enable = 'off';
                app.EditField16.Value  = app.B1betaEditField.Value;
                app.EditField17.Value  = app.B1betaEditField.Value;
                app.ConstantbetaButton.Value = true;
                app.stepfittingCheckBox.Value = false;
                app.stepfittingCheckBox.Enable = 'off';
            elseif strcmp(app.Params.ScanType, 'IR_VFA')
                app.cLEditField.Value = 5;
                app.T2EditField.Enable = 'off';
                app.EditField_2.Enable = 'off';
                app.EditField16.Value  = 0.1;
                app.EditField17.Value  = 1.0;
                app.SingleInvEfficiencyButton.Value = true;
                app.stepfittingCheckBox.Enable = 'on';
            else
                app.cLEditField.Value = 10;
                app.T2EditField.Enable = 'on';
                app.EditField_2.Enable = 'on';
            end

            app.NewCoilEditField.Value = min(app.NewCoilEditField.Value,size(app.DataArray.navData,4));
            app.NcoilsEditFieldLabel.Text = 'Ncoils';
            app.EchospacingmsEditField.Editable = 'off';
            app.NkyEditField.Editable = 'off';
            app.NkzEditField.Editable = 'off';
            
            app.ReconOptions.dispSlice = floor(app.Params.Nz/2) + 1;

            if strcmp(app.Params.Trajectory,'Radial')
                app.GradientdelaycorrectionCheckBox.Enable = 'on';
                app.EddycurrentcompensationCheckBox.Enable   = 'on';
            end
            app.NewFOVroFOVEditField.Enable  = 'on';  
            app.NewFOVroFOVEditFieldLabel.Enable = 'on';
            app.SetupTrajectoryButton.Enable = 'on';
            app.CoilCompressionButton.Enable = 'off';
            app.EstimateSensitivityMapButton.Enable = 'off';
            app.RealtimeReconButton.Enable = 'off';
            app.DropDown.Enable = 'off';
            
            app.Lamp.Color   = [0 1 0];
            updateSuccessStatus(app, ' K-space data loaded.');
        end

        % Value changed function: ScanLengthEditField
        function ScanLengthEditFieldValueChanged(app, event)
            if isfinite(str2double(app.ScanLengthEditField.Value))    
                app.ReconOptions.totalTime = str2double(app.ScanLengthEditField.Value);
            else
                app.ReconOptions.totalTime = inf;
            end
            app.Lamp.Color = [0.8 0.8 0.8];
        end

        % Value changed function: TrajectoryEditField
        function TrajectoryEditFieldValueChanged(app, event)
            value = app.TrajectoryEditField.Value;
            app.Params.Trajectory = value;
        end

        % Value changed function: IREditField
        function IREditFieldValueChanged(app, event)
            value = app.IREditField.Value;
            if strcmp(value,'off')
                app.Params.numIRns = 0;
            else
                app.Params.numIRns = 1;
                app.IREditField.Value = 'on';
            end
            if app.Params.numFA > 0 && (app.Params.numIRns + app.Params.numT2prep + app.Params.numT1rhoPrep) > 0
                app.Params.moduleLength = lcm(app.Params.numFA,(app.Params.numIRns + app.Params.numT2prep + app.Params.numT1rhoPrep));
            else
                app.Params.moduleLength = max(max(app.Params.numFA,(app.Params.numIRns + app.Params.numT2prep + app.Params.numT1rhoPrep)),1);
            end
            if app.Params.numIRns > 0 && app.Params.numT2prep == 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA == 1
                app.Params.ScanType = 'IR';
            elseif app.Params.numIRns > 0 && app.Params.numT2prep == 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA > 1
                app.Params.ScanType = 'IR_VFA';
            elseif app.Params.numT2prep > 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA == 1
                app.Params.ScanType = 'T2IR';
            elseif app.Params.numT2prep > 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA > 1
                app.Params.ScanType = 'T2IR_VFA';
            end 
            app.ScanTypeEditField.Value = app.Params.ScanType;
            app.Lamp.Color = [0.8 0.8 0.8];
            app.Lamp_9.Color = [0.8 0.8 0.8];
        end

        % Value changed function: T2IRmsEditField
        function T2IRmsEditFieldValueChanged(app, event)
            value = regexp(app.T2IRmsEditField.Value,'[ ,;]','split');
            app.Params.numT2prep = numel(value);
            for n = 1:app.Params.numT2prep
                if isfinite(str2double(value{n}))
                    app.Params.T2prepDuration(n) = str2double(value{n});
                else
                    app.T2IRmsEditField.Value = '--';
                    app.Params.numT2prep = 0;
                    break;
                end
            end
            if app.Params.numFA > 0 && (app.Params.numIRns + app.Params.numT2prep + app.Params.numT1rhoPrep) > 0
                app.Params.moduleLength = lcm(app.Params.numFA,(app.Params.numIRns + app.Params.numT2prep + app.Params.numT1rhoPrep));
            else
                app.Params.moduleLength = max(max(app.Params.numFA,(app.Params.numIRns + app.Params.numT2prep + app.Params.numT1rhoPrep)),1);
            end
            if app.Params.numIRns > 0 && app.Params.numT2prep == 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA == 1
                app.Params.ScanType = 'IR';
            elseif app.Params.numIRns > 0 && app.Params.numT2prep == 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA > 1
                app.Params.ScanType = 'IR_VFA';
            elseif app.Params.numT2prep > 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA == 1
                app.Params.ScanType = 'T2IR';
            elseif app.Params.numT2prep > 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA > 1
                app.Params.ScanType = 'T2IR_VFA';
            end 
            app.ScanTypeEditField.Value = app.Params.ScanType;
            app.Lamp.Color = [0.8 0.8 0.8];
            app.Lamp_9.Color = [0.8 0.8 0.8];
        end

        % Callback function
        function T1rhoprepmsEditFieldValueChanged(app, event)
            value = regexp(app.T1rhoprepmsEditField.Value,'[ ,;]','split');
            app.Params.numT1rhoPrep = numel(value);
            for n = 1:app.Params.numT1rhoPrep
                if isfinite(str2double(value{n}))
                    app.Params.T1rhoDuration(n) = str2double(value{n});
                else
                    app.T1rhoprepmsEditField.Value = '--';
                    app.Params.numT1rhoPrep = 0;
                    break;
                end
            end
            if app.Params.numFA > 0 && (app.Params.numIRns + app.Params.numT2prep + app.Params.numT1rhoPrep) > 0
                app.Params.moduleLength = lcm(app.Params.numFA,(app.Params.numIRns + app.Params.numT2prep + app.Params.numT1rhoPrep));
            else
                app.Params.moduleLength = max(max(app.Params.numFA,(app.Params.numIRns + app.Params.numT2prep + app.Params.numT1rhoPrep)),1);
            end
            if app.Params.numIRns > 0 && app.Params.numT2prep == 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA == 1
                app.Params.ScanType = 'IR';
            elseif app.Params.numIRns > 0 && app.Params.numT2prep == 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA > 1
                app.Params.ScanType = 'IR_VFA';
            elseif app.Params.numT2prep > 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA == 1
                app.Params.ScanType = 'T2IR';
            elseif app.Params.numT2prep > 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA > 1
                app.Params.ScanType = 'T2IR_VFA';
            end 
            app.ScanTypeEditField.Value = app.Params.ScanType;
            app.Lamp.Color = [0.8 0.8 0.8];
            app.Lamp_9.Color = [0.8 0.8 0.8];
        end

        % Value changed function: MBFactorEditField
        function MBFactorEditFieldValueChanged(app, event)
            app.Params.MBfactor = floor(app.MBFactorEditField.Value);
            app.Lamp.Color = [0.8 0.8 0.8];
            app.Lamp_9.Color = [0.8 0.8 0.8];
        end

        % Button pushed function: SetupTrajectoryButton
        function SetupTrajectoryButtonPushed(app, event)
            app.Lamp_2.Color = [1 0 0]; 
            app.Lamp_4.Color = [0.8 0.8 0.8];
            app.Lamp_5.Color = [0.8 0.8 0.8];
            app.Lamp_6.Color = [0.8 0.8 0.8];
            app.ReconOptions.flagGradientDelayCorr = app.GradientdelaycorrectionCheckBox.Value;     % 0: no gradient delay correction; 1: old method; 2: new method
            app.ReconOptions.flagEddyCurrentCorr   = app.EddycurrentcompensationCheckBox.Value;
            app.ReconOptions.cropFOV               = app.NewFOVroFOVEditField.Value;
            
            updateStatus(app, ' Setting up k-space trajectory ......');
            [app.Params, app.ReconOptions, app.DataArray] = setupTrajectories(app.Params, app.ReconOptions, app.DataArray);
            
            app.StatusEditField.Value = ' Pre-whitening ......'; drawnow();
            [app.Params,app.ReconOptions,app.DataArray] = preWhiten(app.Params, app.ReconOptions,app.DataArray, app.ReconOptions.cropFOV);
            app.NxEditField.Value = app.Params.Nxdisp;
            app.FOVrommEditField.Value = app.Params.dReadoutFOV_mm/app.NewFOVroFOVEditField.Value;
            
            updateStatus(app, ' Back-projection ......');
            [app.Params, app.ReconOptions, app.DataArray] = calcfbp(app.Params, app.ReconOptions, app.DataArray);
            
            app.Label_4.Text = 'navData';
            imgdisp = abs(app.DataArray.navData(:,:,1,1))';
            imgdisp = round(256*imgdisp/max(imgdisp(:)));
            I = imshow(imgdisp,jet,'Parent',app.UIAxes_2,'XData',[1 app.UIAxes_2.Position(3)],'YData',[1 app.UIAxes_2.Position(4)]);
            app.UIAxes_2.XLim = [0 I.XData(2)];
            app.UIAxes_2.YLim = [0 I.YData(2)];
            
            if strcmp(app.Params.Trajectory,'Radial')
                app.NkyEditField.Value = app.Params.Ntrajs;
            end
            
            if app.NewCoilEditField.Value > app.Params.Ncoils
                app.NewCoilEditField.Value = app.Params.Ncoils;
            end
            
            if app.Params.Ncoils <= 12
                app.NewCoilEditField.Value = app.Params.Ncoils;
                app.EnergyEditField.Value = 100;
            else
%                 app.NewCoilEditField.Value = find(app.DataArray.coilEnergycumsum*100>=app.EnergyEditField.Value-0.01,1);
                app.EnergyEditField.Value  = double(app.DataArray.coilEnergycumsum(app.NewCoilEditField.Value)*100.0);
            end
            app.ReconOptions.mincoilEnergy = app.EnergyEditField.Value/100.0 - 0.005;
            app.ReconOptions.useNewCoilsAuto = false;

            app.NewCoilEditField.Enable = 'on';
            app.NewCoilEditFieldLabel.Enable = 'on';
            app.EnergyEditField.Enable = 'on';
            app.EnergyEditFieldLabel.Enable = 'on';
            app.Label_3.Enable = 'on';
            
            app.Lamp_2.Color = [0 1 0];  
            app.SetupTrajectoryButton.Enable = 'off';
            app.NewFOVroFOVEditField.Enable  = 'off';
            app.NewFOVroFOVEditFieldLabel.Enable = 'off';
            app.GradientdelaycorrectionCheckBox.Enable = 'off';
            app.EddycurrentcompensationCheckBox.Enable   = 'off';
            app.CoilCompressionButton.Enable = 'on';
            
            updateSuccessStatus(app, ' Trajectory setup done.');
        end

        % Button pushed function: CoilCompressionButton
        function CoilCompressionButtonPushed(app, event)
            app.Lamp_4.Color = [1 0 0];
            updateStatus(app, ' Coil compression ......');
            
            app.ReconOptions.newCoils = app.NewCoilEditField.Value;
            app.ReconOptions.minNewCoils = app.NewCoilEditField.Value;
            
            [app.Params, app.ReconOptions, app.DataArray] = coilCompression(app.Params, app.ReconOptions, app.DataArray);

            updateSuccessStatus(app, [' Coild Compression finished. Virtual coil number = ' num2str(app.Params.Ncoils)]);
            
            app.NcoilsEditFieldLabel.Text = 'Ncoils (compressed)';
            app.NcoilsEditField.Value = app.Params.Ncoils;
            if app.NewCoilEditField.Value > app.Params.Ncoils
                app.NewCoilEditField.Value = app.Params.Ncoils;
            end
            app.EnergyEditField.Value  = double(app.DataArray.coilEnergycumsum(app.NewCoilEditField.Value)*100);
            
            app.Lamp_4.Color = [0 1 0];
            app.Lamp_5.Color = [0.8 0.8 0.8];
            app.Lamp_6.Color = [0.8 0.8 0.8];

            app.EstimateSensitivityMapButton.Enable = 'on';
            app.CBDButton.Enable = 'on';
            app.WalshButton.Enable = 'on';
            app.ESPIRiTButton.Enable = 'on';
            app.ExportDICOMButton.Enable = 'on';
            app.PlayMovieButton.Enable = 'on';
            app.SaveGifButton.Enable = 'on';
            
            app.WWSlider.Enable = 'on';
            app.WWSliderLabel.Enable = 'on';
            app.FlipLRButton.Enable = 'on';
            app.FlipUDButton.Enable = 'on';
            app.RotateButton.Enable = 'on';
            app.SliceSlider_3.Enable = 'on';
            app.TimeSlider.Enable = 'on';
            app.UIDisplay.xlabel = 'Time ';
            app.UIDisplay.ylabel = 'Slice ';
            app.DropDown.Enable = 'on';
            app.DropDown.Value = 'Volume Mean';
            app.DropDown.Items{2} = 'Coil Image';
            app.UIDisplay.currentDisplayPreproc = imageOrientLPS(abs(app.DataArray.fbpComposite),app.Params);
            updatePreProcAxesImage(app);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            app.TimeSlider.Value = min(round(event.Value),size(app.UIDisplay.currentDisplayPreproc,4));
            if strcmp(app.DropDown.Value,'Real-time Image')
                app.TimeSliderLabel.Text = ['Time ' num2str(1000*app.ReconstructedImages.reconRealtimeFrameTime*app.TimeSlider.Value,'%.1f') 'ms'];
            else
                app.TimeSliderLabel.Text = [app.UIDisplay.xlabel num2str(app.TimeSlider.Value)];
            end
            updatePreProcAxes(app);
        end

        % Value changing function: SliceSlider_3
        function SliceSlider_3ValueChanging(app, event)
            app.SliceSlider_3.Value = min(round(event.Value),size(app.UIDisplay.currentDisplayPreproc,3));
            app.SliceSlider_3Label.Text = [app.UIDisplay.ylabel num2str(app.SliceSlider_3.Value)];
            updatePreProcAxes(app);
        end

        % Button pushed function: FlipUDButton
        function FlipUDButtonPushed(app, event)
            app.UIDisplay.currentDisplayPreproc = flipud(app.UIDisplay.currentDisplayPreproc);
            app.UIDisplay.matFlipRot(:,1) = -app.UIDisplay.matFlipRot(:,1);
            updatePreProcAxes(app);
        end

        % Button pushed function: FlipLRButton
        function FlipLRButtonPushed(app, event)
            app.UIDisplay.currentDisplayPreproc = fliplr(app.UIDisplay.currentDisplayPreproc);
            app.UIDisplay.matFlipRot(:,2) = -app.UIDisplay.matFlipRot(:,2);
            updatePreProcAxes(app);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            app.UIDisplay.currentDisplayPreproc = imrotate(app.UIDisplay.currentDisplayPreproc,90);
            app.UIDisplay.matFlipRot = [0 -1;1 0]*app.UIDisplay.matFlipRot;
            updatePreProcAxes(app);
        end

        % Value changed function: SliceSlider_3
        function SliceSlider_3ValueChanged(app, event)
            app.SliceSlider_3.Value = min(round(app.SliceSlider_3.Value),size(app.UIDisplay.currentDisplayPreproc,3));
            app.SliceSlider_3Label.Text = [app.UIDisplay.ylabel num2str(app.SliceSlider_3.Value)];
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            app.TimeSlider.Value = min(round(app.TimeSlider.Value),size(app.UIDisplay.currentDisplayPreproc,4));
            if strcmp(app.DropDown.Value,'Real-time Image')
                app.TimeSliderLabel.Text = ['Time ' num2str(1000*app.ReconstructedImages.reconRealtimeFrameTime*app.TimeSlider.Value,'%.1f') 'ms'];
            else
                app.TimeSliderLabel.Text = [app.UIDisplay.xlabel num2str(app.TimeSlider.Value)];
            end
        end

        % Value changed function: UseGPUCheckBox
        function UseGPUCheckBoxValueChanged(app, event)
            app.ReconOptions.flagUseGPU = app.UseGPUCheckBox.Value;
        end

        % Value changing function: WWSlider
        function WWSliderValueChanging(app, event)
            app.WWSlider.Value = event.Value;
            updatePreProcAxes(app);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if strcmp(app.DropDown.Value,'Volume Mean')
                app.UIDisplay.currentDisplayPreproc = imageOrientLPS(abs(app.DataArray.fbpComposite),app.Params);
                app.UIDisplay.xlabel = '  ';
                app.TimeSliderLabel.Text = '   1';
            elseif strcmp(app.DropDown.Value,'Coil Image')
                app.UIDisplay.currentDisplayPreproc = imageOrientLPS(abs(app.DataArray.fbp),app.Params);
                app.UIDisplay.xlabel = 'Coil #';
                app.TimeSliderLabel.Text = 'Coil #1';
            elseif strcmp(app.DropDown.Value,'Sensitivity')
                app.UIDisplay.currentDisplayPreproc = imageOrientLPS(abs(app.DataArray.SEs),app.Params);
                app.UIDisplay.xlabel = 'Coil #';
                app.TimeSliderLabel.Text = 'Coil #1';
            elseif strcmp(app.DropDown.Value,'Real-time Image')
                app.UIDisplay.currentDisplayPreproc = imageOrientLPS(abs(app.ReconstructedImages.reconRealtime),app.Params);
                app.UIDisplay.xlabel = ['Time ' num2str(1000*app.ReconstructedImages.reconRealtimeFrameTime,'%.1f') 'ms'];
                app.TimeSliderLabel.Text = [app.UIDisplay.xlabel '1'];
            end
            updatePreProcAxesImage(app);
        end

        % Button pushed function: EstimateSensitivityMapButton
        function EstimateSensitivityMapButtonPushed(app, event)
            if app.CBDButton.Value
                app.ReconOptions.SEmethod = 'CBD';
            elseif app.WalshButton.Value
                app.ReconOptions.SEmethod = 'Walsh';
            else
                app.ReconOptions.SEmethod = 'ESPIRiT';
            end
            app.ReconOptions.dispSlice = floor(app.Params.Nz/2) + 1;
            
            app.Lamp_5.Color = [1 0 0];
            app.Lamp_6.Color = [0.8 0.8 0.8];
           
            updateStatus(app, ' Estimating sensitivity maps ......');
            [app.Params, app.ReconOptions, app.DataArray] = estimateSensitivities(app.Params, app.ReconOptions, app.DataArray);
            
            app.UIDisplay.currentDisplayPreproc = imageOrientLPS(abs(app.DataArray.SEs),app.Params);
            app.DropDown.Items{3} = 'Sensitivity';
            app.DropDown.Value = 'Sensitivity';
            app.UIDisplay.xlabel = 'Coil #';
            app.TimeSliderLabel.Text = 'Coil #1';
            updatePreProcAxesImage(app);
            
            app.RealtimeReconButton.Enable = 'on';
            app.RankEditField.Enable = 'on';
            app.RankEditFieldLabel.Enable = 'on';
            
            app.Lamp_5.Color = [0 1 0];
            updateStatus(app, ' Check sensitivity maps.');
            
            close all;
        end

        % Button pushed function: RealtimeReconButton
        function RealtimeReconButtonPushed(app, event)
            app.Lamp_6.Color = [1 0 0];
            app.ReconOptions.L_rt = app.RankEditField.Value;
           
            updateStatus(app, ' Real-time recon ......');
            [app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis] = realtimeSubspace(app.Params, app.ReconOptions, app.DataArray,app.TemporalBasis);
            
            app.RankEditField.Value = size(app.TemporalBasis.Phi_rt,1);
            
            calcUIPlotPhi(app);
            app.DropDown_7.Enable = 'on';
            DropDown_7ValueChanged(app, event);
            
            plotlength = ceil(10.01*app.Params.linesPerShot/app.Params.SGBlock);
            line = realify(app.TemporalBasis.Phi_rt_small(1,1:plotlength),'rows');
            plot(app.UIAxes_2,line);axis(app.UIAxes_2,'on');
            app.UIAxes_2.XLim = [0 plotlength];
            app.UIAxes_2.YLim = [min(line(:)) max(line(:))]*1.05;
            app.UIAxes_2.YDir = 'normal';
            app.UIAxes_2.DataAspectRatio = [plotlength/360 (max(line(:))-min(line(:)))*1.05/168 1];
            app.Label_4.Text = 'Phi_rt_small';drawnow();
     
            app.ReconOptions.flagUseInitialGuess = false; 
            app.ReconOptions.flagUseToeplitz = false; 
            [app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff] = reconLeastSquares(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis);

            app.ReconstructedImages = displayRealtime(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff,app.ReconstructedImages,floor(app.Params.Nz/2)+1);
            app.UIDisplay.currentDisplayPreproc = imageOrientLPS(abs(app.ReconstructedImages.reconRealtime),app.Params);
            app.DropDown.Items{4} = 'Real-time Image';
            app.DropDown.Value = 'Real-time Image';
            app.UIDisplay.xlabel = ['Time ' num2str(1000*app.ReconstructedImages.reconRealtimeFrameTime, '%.1f') 'ms x '];
            app.TimeSliderLabel.Text = ['Time ' num2str(1000*app.ReconstructedImages.reconRealtimeFrameTime*app.TimeSlider.Value,'%.1f') 'ms'];
            updatePreProcAxesImage(app);
           
            app.GeneratecurvePhiButton.Enable = 'on';
            app.Lamp_6.Color = [0 1 0];
            updateSuccessStatus(app, ' Real-time recon done.');
        end

        % Button pushed function: RunAllPreprocessingButton
        function RunAllPreprocessingButtonPushed(app, event)
            LoadDataButtonPushed(app, event);
            SetupTrajectoryButtonPushed(app, event);
            CoilCompressionButtonPushed(app, event);
            EstimateSensitivityMapButtonPushed(app, event);
            RealtimeReconButtonPushed(app, event);
            GeneratecurvePhiButtonPushed(app, event);
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
            idxPhi = str2double(app.DropDown_7.Value);
            linePhi = app.UIDisplay.Phi(idxPhi,:);
            plot(app.UIAxes_8,linePhi); %axis(app.UIAxes_8,'on');
            app.UIAxes_8.XLim = [0 numel(linePhi)];
            app.UIAxes_8.YLim = [min(linePhi(:)) max(linePhi(:))]*1.05;
            app.UIAxes_8.YDir = 'normal';
            app.UIAxes_8.DataAspectRatio = [numel(linePhi)/360 (max(linePhi(:))-min(linePhi(:)))*1.05/63 1];
            
            linePhiFFT = app.UIDisplay.PhiFFT(idxPhi,:);
            fgrid = linspace(-2.5,2.5,numel(linePhiFFT));
            plot(app.UIAxes_9,fgrid,linePhiFFT); %axis(app.UIAxes_9,'on');
            app.UIAxes_9.XLim = [-2.5 2.5];
            app.UIAxes_9.YLim = [0 max(linePhiFFT(:))]*1.05;
            app.UIAxes_9.YDir = 'normal';
            app.UIAxes_9.DataAspectRatio = [5/360 max(linePhiFFT(:))*1.05/63 1];
            drawnow();
        end

        % Button pushed function: GeneratecurvePhiButton
        function GeneratecurvePhiButtonPushed(app, event)
            app.Lamp_9.Color = [1 0 0];
            app.FitParams.minT1 = app.T1EditField.Value/1000;
            app.FitParams.maxT1 = app.EditField.Value/1000;
            app.FitParams.minT2 = app.T2EditField.Value/1000;
            app.FitParams.maxT2 = app.EditField_2.Value/1000;
            
            updateStatus(app,' Generating curvePhi ...');
            [app.TemporalBasis,app.FitParams] = genBlochSubspace(app.Params, app.FitParams, app.ReconOptions, app.TemporalBasis, app.cLEditField.Value);
            
            plotlength = size(app.TemporalBasis.curvePhi,1);
            lines = realify(app.TemporalBasis.curvePhi,'cols');
            margin = max(abs(lines(:)))*0.05;
            pos = app.UIAxes_2.InnerPosition;
            plot(app.UIAxes_2,lines); %axis(app.UIAxes_2,'on');
            app.UIAxes_2.XLim = [0 plotlength];
            app.UIAxes_2.YLim = [min(lines(:))-margin max(lines(:))+margin];
            app.UIAxes_2.YDir = 'normal';
            app.UIAxes_2.DataAspectRatio = [plotlength/pos(3) (max(lines(:))-min(lines(:))+margin*2)/pos(4) 1];
            app.Label_4.Text = 'curvePhi';
            
            app.cLEditField.Value = size(app.TemporalBasis.curvePhi,2);
            
            if app.ChestButton.Value || app.AbdomenButton.Value
                app.RespiratoryBinsEditField.Value = 6;
            else
                app.RespiratoryBinsEditField.Value = 1;
            end
            if app.ChestButton.Value
                app.CardiacBinsEditField.Value = 20;
            else
                app.CardiacBinsEditField.Value = 1;
            end
            
            app.RespBinningButton.Enable = 'on';
            app.RunAllButton_3.Enable = 'on';
            app.Lamp_9.Color = [0 1 0];
            updateStatus(app,' Generating curvePhi ... Done.');
        end

        % Button pushed function: RespBinningButton
        function RespBinningButtonPushed(app, event)
            app.Lamp_7.Color = [1 0 0];
            app.Lamp_8.Color = [0.8 0.8 0.8];
            app.Lamp_14.Color = [0.8 0.8 0.8];
            updateStatus(app,' Respiratory motion binning ...');
            
            app.ReconOptions.rbins = app.RespiratoryBinsEditField.Value;  
            app.ReconOptions.flagUseEMD = false;
            app.ReconOptions.dispSlice = floor(app.Params.Nz/2) + 1;
            app.ReconOptions.BRlow  = app.RespFreqEditField.Value;
            app.ReconOptions.BRhigh = app.EditField_3.Value;
            
            % Respiratory binning
            app.DataArray = binningResp(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff,app.ManualROICheckBox.Value);
            
            Ridx = app.DataArray.Ridx;
            pos = get(app.UIAxes_3,'InnerPosition');
            app.UIDisplay.posUIAxes_3 = pos;
            plotlength = numel(Ridx);
            plot(app.UIAxes_3,Ridx,'.-'); 
            %posDiff = pos - app.UIAxes_3.InnerPosition;
            %app.UIAxes_3.Position = app.UIAxes_3.InnerPosition + posDiff;
            app.UIAxes_3.XLim = [0 plotlength];
            app.UIAxes_3.YLim = [0 app.ReconOptions.rbins+1];
            app.UIAxes_3.YDir = 'normal';
            app.UIAxes_3.DataAspectRatio = [plotlength/pos(3) (app.ReconOptions.rbins+1)/pos(4) 1];
               
            Segidx = mod(app.DataArray.navIndices-1,app.Params.linesPerShot*app.Params.moduleLength) + 1;
            pos = get(app.UIAxes_11,'InnerPosition');
            app.UIDisplay.posUIAxes_11 = pos;
            plot(app.UIAxes_11,Segidx,Ridx,'.'); 
            %posDiff = pos - app.UIAxes_11.InnerPosition;
            %app.UIAxes_11.Position = app.UIAxes_11.InnerPosition + posDiff;
            app.UIAxes_11.XLim = [0 max(Segidx)];
            app.UIAxes_11.YLim = [0 app.ReconOptions.rbins+1];
            app.UIAxes_11.YDir = 'normal';
            app.UIAxes_11.DataAspectRatio = [max(Segidx)/pos(3) (app.ReconOptions.rbins+1)/pos(4) 1];
            
            app.UIDisplay.xlabelBin = 'Bin # ';
            app.BinSliderLabel.Text = [app.UIDisplay.xlabelBin '1'];
            app.UIDisplay.ylabelBin = 'Slice # ';
            app.SliceSlider_4Label.Text = [app.UIDisplay.ylabelBin '1'];
            app.UIDisplay.currentBinning = imageOrientLPS(app.DataArray.binsRespMean,app.Params);
            updateBinningAxesImage(app);
            
            app.DropDown_2.Value = app.DropDown_2.Items{1};
%             app.DropDown_2.Items{2} = 'Resp Single Bin';
            app.DropDown_2.Enable = 'on';
            app.DropDown_3.Value = app.DropDown_3.Items{1};
            app.DropDown_3.Items(2:end) = [];
            for n = 2:max(app.ReconOptions.rbins)
                app.DropDown_3.Items{n} = num2str(n);
            end
            
            if app.ReconOptions.rbins == 1
                app.smsEditField_2.Value  = 0;
                app.smsEditField_2.Enable = 'off';
            else
                app.smsEditField_2.Value  = 1e-6;
                app.smsEditField_2.Enable = 'on';
                app.MoCoButton.Enable = 'on';
                app.RemoveBinsButton.Enable = 'on';
            end
            app.SliceSlider_4.Enable = 'on';
            app.BinSlider.Enable = 'on';
            app.WWSlider_2.Enable = 'on';
            app.FlipRidxButton.Enable = 'on';
            app.CardiacBinningButton.Enable = 'on';
            app.PlayMovieButton_2.Enable = 'on';
            app.Lamp_7.Color = [0 1 0];
            updateSuccessStatus(app,' Respiratory motion binning done. Check if Ridx needs to be flipped.');
        end

        % Button pushed function: FlipRidxButton
        function FlipRidxButtonPushed(app, event)
            app.DataArray = flipRidx(app.DataArray,false);
             
            Ridx = app.DataArray.Ridx;
            pos = app.UIDisplay.posUIAxes_3;
            plotlength = numel(Ridx);
            plot(app.UIAxes_3,Ridx,'.-'); 
            app.UIAxes_3.XLim = [0 plotlength];
            app.UIAxes_3.YLim = [0 app.ReconOptions.rbins+1];
            app.UIAxes_3.YDir = 'normal';
            app.UIAxes_3.DataAspectRatio = [plotlength/pos(3) (app.ReconOptions.rbins+1)/pos(4) 1];
               
            Segidx = mod(app.DataArray.navIndices-1,app.Params.linesPerShot*app.Params.moduleLength) + 1;
            pos = app.UIDisplay.posUIAxes_11;
            plot(app.UIAxes_11,Segidx,Ridx,'.'); 
            app.UIAxes_11.XLim = [0 max(Segidx)];
            app.UIAxes_11.YLim = [0 app.ReconOptions.rbins+1];
            app.UIAxes_11.YDir = 'normal';
            app.UIAxes_11.DataAspectRatio = [max(Segidx)/pos(3) (app.ReconOptions.rbins+1)/pos(4) 1];
            
            app.UIDisplay.currentBinning = imageOrientLPS(app.DataArray.binsRespMean,app.Params);
            updateBinningAxesImage(app);   
            
            app.DropDown_2.Value = app.DropDown_2.Items{1};
            app.DropDown_3.Value = app.DropDown_3.Items{1};
            app.DropDown_3.Enable = 'off';
        end

        % Button pushed function: CardiacBinningButton
        function CardiacBinningButtonPushed(app, event)
            app.Lamp_8.Color = [1 0 0];
            updateStatus(app,' Cardiac motion binning...');
            
            app.ReconOptions.cbins = app.CardiacBinsEditField.Value;
            app.ReconOptions.HRlow  = app.CardiacFreqEditField.Value;
            app.ReconOptions.HRhigh = app.EditField_4.Value;
            
            [app.DataArray,app.TemporalBasis] = binningCard(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff,app.ManualROICheckBox_2.Value);
            % Another binning method
            % [app.DataArray,app.TemporalBasis] = binningCard2(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff);
            
            app.DataArray.diastoleIdx = 1;
            app.DataArray.systoleIdx = 11;
            
            Hidx = app.DataArray.Hidx;
            pos = get(app.UIAxes_6,'InnerPosition');
            app.UIDisplay.posUIAxes_6 = pos;
            plotlength = numel(Hidx);
            plot(app.UIAxes_6,1:plotlength,Hidx,'.-'); %axis(app.UIAxes_2,'on');
            app.UIAxes_6.XLim = [0 plotlength];
            app.UIAxes_6.YLim = [0 app.ReconOptions.cbins+1];
            app.UIAxes_6.YDir = 'normal';
            app.UIAxes_6.DataAspectRatio = [plotlength/pos(3) (app.ReconOptions.cbins+1)/pos(4) 1];

            Segidx = mod(app.DataArray.navIndices-1,app.Params.linesPerShot*app.Params.moduleLength) + 1;
            Ridx = app.DataArray.Ridx;
            pos = get(app.UIAxes_12,'InnerPosition');
            app.UIDisplay.posUIAxes_12 = pos;
            cmap = parula(8);
            plot(app.UIAxes_12,Segidx(Ridx==1),Hidx(Ridx==1),'.','Color',cmap(1,:));hold(app.UIAxes_12,'on');
            for rphase = 1:app.ReconOptions.rbins
                plot(app.UIAxes_12,Segidx(Ridx==rphase),Hidx(Ridx==rphase),'.','Color',cmap(mod(rphase-1,8)+1,:)); 
            end
            hold(app.UIAxes_12,'off');
            %posDiff = pos - app.UIAxes_12.InnerPosition;
            %app.UIAxes_12.Position = app.UIAxes_12.InnerPosition + posDiff;
            app.UIAxes_12.XLim = [0 max(Segidx)];
            app.UIAxes_12.YLim = [0 app.ReconOptions.cbins+1];
            app.UIAxes_12.YDir = 'normal';
            app.UIAxes_12.DataAspectRatio = [max(Segidx)/pos(3) (app.ReconOptions.cbins+1)/pos(4) 1];
            
            app.DropDown_2.Items{2} = 'Cardiac Motion';
            app.DropDown_2.Value  = app.DropDown_2.Items{2};
            app.DropDown_2.Enable = 'on';
            app.DropDown_3.Enable = 'on';
            
            app.BinSliderLabel.Text = [app.UIDisplay.xlabelBin '1'];
            app.SliceSlider_4Label.Text = [app.UIDisplay.ylabel '1'];
            app.UIDisplay.currentBinning = imageOrientLPS(abs(app.DataArray.binsCard(:,:,:,:,1)),app.Params);
            updateBinningAxesImage(app);  
            
            if app.ReconOptions.cbins == 1
                app.smsEditField.Value  = 0;
                app.smsEditField.Enable = 'off';
            else
                app.smsEditField.Value  = 3e-6;
                app.smsEditField.Enable = 'on';
            end

            app.RunAllButton_2.Enable = 'on';
            app.SetupTensorParametersButton.Enable = 'on';
            app.SetasDiastoleButton.Enable = 'on';
            app.Lamp_8.Color = [0 1 0];
            updateSuccessStatus(app,' Cardiac motion binning... done. Re-set end-of-diastole phase if necessary');
        end

        % Value changed function: SGBlockEditField
        function SGBlockEditFieldValueChanged(app, event)
            app.Params.SGBlock = app.SGBlockEditField.Value;        
            app.Lamp.Color   = [0.8 0.8 0.8];
            app.Lamp_9.Color = [0.8 0.8 0.8];
        end

        % Value changed function: LinesperShotEditField
        function LinesperShotEditFieldValueChanged(app, event)
            app.Params.linesPerShot = app.LinesperShotEditField.Value; 
            app.TotalFramesEditField.Value = app.Params.linesPerShot;
            app.Lamp.Color   = [0.8 0.8 0.8];
            app.Lamp_9.Color = [0.8 0.8 0.8];
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if strcmp(value,'Resp Motion')
                app.DropDown_3.Enable = 'off';
                app.UIDisplay.xlabelBin = 'Bin # ';
                app.BinSliderLabel.Text = 'Bin # 1';
                app.UIDisplay.currentBinning = imageOrientLPS(abs(app.DataArray.binsRespMean),app.Params);
                updateBinningAxesImage(app);  
            elseif strcmp(value,'Resp Single Bin')
                app.DropDown_3.Enable = 'on';
                app.UIDisplay.xlabelBin = 'Frame # ';
                app.BinSliderLabel.Text = 'Frame # 1';
                app.UIDisplay.currentBinning = imageOrientLPS(abs(app.DataArray.binsResp{str2double(app.DropDown_3.Value)}),app.Params);
                updateBinningAxesImage(app); 
            else
                app.DropDown_3.Value = app.DropDown_3.Items{1};
                app.DropDown_3.Enable = 'on';
                app.UIDisplay.xlabelBin = 'Bin # ';
                app.BinSliderLabel.Text = 'Bin # 1';
                app.UIDisplay.currentBinning = imageOrientLPS(abs(app.DataArray.binsCard(:,:,:,:,1)),app.Params);
                updateBinningAxesImage(app);  
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = str2double(app.DropDown_3.Value);
            if strcmp(app.DropDown_2.Value,'Resp Single Bin')
                app.UIDisplay.currentBinning = imageOrientLPS(abs(app.DataArray.binsResp{value}),app.Params);
            else
                app.UIDisplay.currentBinning = imageOrientLPS(abs(app.DataArray.binsCard(:,:,:,:,value)),app.Params);
            end
            updateBinningAxesImage(app);   
        end

        % Value changing function: SliceSlider_4
        function SliceSlider_4ValueChanging(app, event)
            app.SliceSlider_4.Value = min(round(event.Value),size(app.UIDisplay.currentBinning,3));
            app.SliceSlider_4Label.Text = [app.UIDisplay.ylabelBin num2str(app.SliceSlider_4.Value)];
            updateBinningAxes(app);
        end

        % Value changed function: SliceSlider_4
        function SliceSlider_4ValueChanged(app, event)
            app.SliceSlider_4.Value = min(round(app.SliceSlider_4.Value),size(app.UIDisplay.currentBinning,3));
            app.SliceSlider_4Label.Text = [app.UIDisplay.ylabelBin num2str(app.SliceSlider_4.Value)];
        end

        % Value changing function: WWSlider_2
        function WWSlider_2ValueChanging(app, event)
            app.WWSlider_2.Value = event.Value;
            updateBinningAxes(app);
        end

        % Value changed function: BinSlider
        function BinSliderValueChanged(app, event)
            app.BinSlider.Value = min(round(app.BinSlider.Value),size(app.UIDisplay.currentBinning,4));
            app.BinSliderLabel.Text = [app.UIDisplay.xlabelBin num2str(app.BinSlider.Value)];
        end

        % Value changing function: BinSlider
        function BinSliderValueChanging(app, event)
            app.BinSlider.Value = min(round(event.Value),size(app.UIDisplay.currentBinning,4));
            app.BinSliderLabel.Text = [app.UIDisplay.xlabelBin num2str(app.BinSlider.Value)];
            updateBinningAxes(app);
        end

        % Button pushed function: SetasDiastoleButton
        function SetasDiastoleButtonPushed(app, event)
            app.DataArray.diastoleIdx = app.BinSlider.Value;
            app.DataArray = shiftHidx(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff);
            
            Hidx = app.DataArray.Hidx;
            pos = app.UIDisplay.posUIAxes_6;
            plotlength = numel(Hidx);
            plot(app.UIAxes_6,Hidx); 
            app.UIAxes_6.XLim = [0 plotlength];
            app.UIAxes_6.YLim = [0 app.ReconOptions.cbins+1];
            app.UIAxes_6.YDir = 'normal';
            app.UIAxes_6.DataAspectRatio = [plotlength/pos(3) (app.ReconOptions.cbins)/pos(4) 1];
            
            Segidx = mod(app.DataArray.navIndices-1,app.Params.linesPerShot*app.Params.moduleLength) + 1;
            Ridx = app.DataArray.Ridx;
            pos = app.UIDisplay.posUIAxes_12;
            cmap = parula(8);
            plot(app.UIAxes_12,Segidx(Ridx==1),Hidx(Ridx==1),'.','Color',cmap(1,:));hold(app.UIAxes_12,'on');
            for rphase = 1:app.ReconOptions.rbins
                plot(app.UIAxes_12,Segidx(Ridx==rphase),Hidx(Ridx==rphase),'.','Color',cmap(mod(rphase-1,8)+1,:)); 
            end
            hold(app.UIAxes_12,'off');
            app.UIAxes_12.XLim = [0 max(Segidx)];
            app.UIAxes_12.YLim = [0 app.ReconOptions.cbins+1];
            app.UIAxes_12.YDir = 'normal';
            app.UIAxes_12.DataAspectRatio = [max(Segidx)/pos(3) (app.ReconOptions.cbins+1)/pos(4) 1];
            
            app.BinSliderLabel.Text = 'Bin # 1';
            app.SliceSlider_4Label.Text = 'Slice 1';
            app.UIDisplay.currentBinning = imageOrientLPS(app.DataArray.binsCard(:,:,:,:,str2double(app.DropDown_3.Value)),app.Params);
            updateBinningAxesImage(app);   
            
            app.DropDown_2.Value = app.DropDown_2.Items{3};
            app.DropDown_3.Value = app.DropDown_3.Items{1};
            app.DropDown_3.Enable = 'on';
        end

        % Button pushed function: PlayMovieButton_2
        function PlayMovieButton_2Pushed(app, event)
            displayNz = app.SliceSlider_4.Value;
            scalar  = app.WWSlider_2.Value;
            imgDisp = app.UIDisplay.currentBinning(:,:,displayNz,:);
            if size(app.UIDisplay.currentBinning,4) > 50
                fps = 100;
            elseif size(app.UIDisplay.currentBinning,4) > 15
                fps = 20;
            else
                fps = floor(size(app.UIDisplay.currentBinning,4)/2);
            end
            implayZoom(imgDisp/scalar,fps);
        end

        % Button pushed function: PlayMovieButton
        function PlayMovieButtonPushed(app, event)
            displayNz = app.SliceSlider_3.Value;
            scalar  = app.WWSlider.Value;
            imgDisp = app.UIDisplay.currentDisplayPreproc(:,:,displayNz,:);
            if size(app.UIDisplay.currentDisplayPreproc,4) > 15
                fps = 20;
            else
                fps = floor(size(app.UIDisplay.currentDisplayPreproc,4)/2);
            end
            implayZoom(imgDisp/scalar,fps);
        end

        % Button pushed function: SetupTensorParametersButton
        function SetupTensorParametersButtonPushed(app, event)
            if app.NoneButton.Value
                app.ReconOptions.lowmem = 0;
            elseif app.navButton.Value
                app.ReconOptions.lowmem = 1;
            else
                app.ReconOptions.lowmem = 2;
            end
            app.ReconOptions.flagAutoLambdaTensor = app.AutolambdaCheckBox.Value;
            if ~app.AutolambdaCheckBox.Value
                app.ReconOptions.tensor.lr = app.lrEditField.Value;
                app.ReconOptions.tensor.sms = [app.smsEditField.Value app.smsEditField_2.Value];
            end
            app.Lamp_10.Color = [1 0 0];
            app.Lamp_12.Color = [0.8 0.8 0.8];
            app.Lamp_13.Color = [0.8 0.8 0.8];
            updateStatus(app, ' Setting up tensor subspace parameters ... (this will take a while)');
            [app.ReconOptions,app.DataArray,app.TempStructTensor] = setTensorParams(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis);

            if app.AutolambdaCheckBox.Value
                app.lrEditField.Value    = double(app.ReconOptions.tensor.lr);
                app.smsEditField.Value   = double(app.ReconOptions.tensor.sms(1));
                app.smsEditField_2.Value = double(app.ReconOptions.tensor.sms(2));
            end
            
            app.TensorSubspaceButton.Enable = 'on';
            app.Lamp_10.Color = [0 1 0];
            updateStatus(app, ' Setting up tensor subspace parameters... done.');
        
        end

        % Button pushed function: TensorSubspaceButton
        function TensorSubspaceButtonPushed(app, event)
            app.Lamp_12.Color = [1 0 0];
            app.Lamp_13.Color = [0.8 0.8 0.8];
            app.ReconOptions.L_tensor = app.RankEditField_2.Value;
            app.ReconOptions.flagUseToeplitz = app.UseToeplitzCheckBox.Value;
            app.ReconOptions.flagUseGPUToeplitz = app.UseGPUCheckBox.Value;
            if app.AutolambdaCheckBox.Value
                app.lrEditField.Value    = double(app.ReconOptions.tensor.lr);
                app.smsEditField.Value   = double(app.ReconOptions.tensor.sms(1));
                app.smsEditField_2.Value = double(app.ReconOptions.tensor.sms(2));
            else
                app.ReconOptions.tensor.lr = app.lrEditField.Value;
            end

            if app.ReconOptions.lowmem == 0
                app.NoneButton.Value = 1;
            elseif app.ReconOptions.lowmem == 1
                app.navButton.Value = 1;
            else
                app.cLButton.Value = 1;
            end
            
            updateStatus(app, ' Estimating tensor subspace ... (this will take a while)');
            [app.DataArray,app.TemporalBasis] = tensorSubspace(app.TempStructTensor,app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis);

            % Least-squares tensor recon
            app.ReconOptions.flagUseInitialGuess = false;    % use the initial guess in LS RECON, Vincent X. Mao 02/04/20
            [app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff]  = reconLeastSquares(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff,true);

            app.TVReconButton.Enable = 'on';
            app.PlayMovieButton_3.Enable = 'on';
            app.SaveGifButton_3.Enable = 'on';
            if app.ChestButton.Value && ~strcmp(app.Params.ScanType,'Cine')
                app.BlackBloodButton.Enable = 'on';
            end
            
            app.FitParams = createFitParams(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff,app.FitParams);
            app.StartFittingButton.Enable = 'on';
            
            app.DropDown_5.Value = '2';
            app.DropDown_5.Enable = 'on';
            app.UIDisplay.currentTensorView = 2;
            app.UIDisplay.xlabelTensor = ['Time ' num2str(1000*app.ReconstructedImages.reconRealtimeFrameTime,'%.1f') 'ms x '];
            app.TimeSlider_2Label.Text = [app.UIDisplay.xlabelTensor '1'];
            app.TimeSlider_2.Enable = 'on';
            app.SliceSlider_5Label.Text = 'Slice 1';
            app.SliceSlider_5.Enable = 'on';
            app.WWSlider_3.Enable = 'on';
            app.ReconstructedImages = genImageTensor(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff,app.ReconstructedImages,2,[],1,1);
            app.UIDisplay.currentTensor = imageOrientLPS(app.ReconstructedImages.reconTensor,app.Params);
            updateTensorAxesImage(app);
   
            app.Lamp_12.Color = [0 1 0];
            updateStatus(app, ' Estimating tensor subspace ... done.');
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = str2double(app.DropDown_5.Value);
            
            if app.UIDisplay.currentTensorView == 2
                displayIdx = [floor(app.Params.linesPerShot/2) 1 1];
            elseif app.UIDisplay.currentTensorView == 3
                displayIdx = [floor(app.Params.linesPerShot/2) app.TimeSlider_2.Value 1];
            else 
                displayIdx = [floor(app.Params.linesPerShot/2) 1 app.TimeSlider_2.Value];
            end
            if app.BlackBloodButton.Value
                displayIdx(1) = abs(app.DataArray.bIdx);
            end

            temp = app.SpatialCoeff;
            if strcmp(app.DropDown_4.Value, 'Tensor')
                temp.U = app.SpatialCoeff.U_tensor;
            end
            app.ReconstructedImages = genImageTensor(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,temp,app.ReconstructedImages,value,displayIdx(1),displayIdx(2),displayIdx(3),app.BlackBloodButton.Value);
            if app.BlackBloodButton.Value
                if value == 2
                    app.ReconstructedImages.reconTensor(app.ReconstructedImages.reconTensor < 0) = 0;
                elseif app.DataArray.bIdx > 0
                    if app.ChestButton.Value
                        app.ReconstructedImages.reconTensor(app.ReconstructedImages.reconTensor < 0) = 0;
                    else
                        app.ReconstructedImages.reconTensor = abs(app.ReconstructedImages.reconTensor);
                    end
                else
                    app.ReconstructedImages.reconTensor(app.ReconstructedImages.reconTensor > 0) = 0;
                end
            end
            
            app.UIDisplay.currentTensor = imageOrientLPS(abs(app.ReconstructedImages.reconTensor),app.Params);
                
            if value == 2
                app.UIDisplay.xlabelTensor = ['Time ' num2str(1000*app.ReconstructedImages.reconRealtimeFrameTime,'%.1f') 'ms x '];
                app.TimeSlider_2Label.Text = [app.UIDisplay.xlabelTensor '1'];
            elseif value == 3
                app.UIDisplay.xlabelTensor = ['Bin #  '];
                app.TimeSlider_2Label.Text = [app.UIDisplay.xlabelTensor '1'];
            else
                app.UIDisplay.xlabelTensor = ['Bin #  '];
                app.TimeSlider_2Label.Text = [app.UIDisplay.xlabelTensor '1'];
            end
            updateTensorAxesImage(app);
            app.UIDisplay.currentTensorView = value;
        end

        % Value changing function: TimeSlider_2
        function TimeSlider_2ValueChanging(app, event)
            app.TimeSlider_2.Value = min(round(event.Value),size(app.UIDisplay.currentTensor,4));
            app.TimeSlider_2Label.Text = [app.UIDisplay.xlabelTensor num2str(app.TimeSlider_2.Value)];
            updateTensorAxes(app);
        end

        % Value changed function: TimeSlider_2
        function TimeSlider_2ValueChanged(app, event)
            app.TimeSlider_2.Value = min(round(event.Value),size(app.UIDisplay.currentTensor,4));
            app.TimeSlider_2Label.Text = [app.UIDisplay.xlabelTensor num2str(app.TimeSlider_2.Value)];
        end

        % Value changing function: SliceSlider_5
        function SliceSlider_5ValueChanging(app, event)
            app.SliceSlider_5.Value = min(round(event.Value),size(app.UIDisplay.currentTensor,3));
            app.SliceSlider_5Label.Text = [app.UIDisplay.ylabel num2str(app.SliceSlider_5.Value)];
            updateTensorAxes(app);
        end

        % Value changed function: SliceSlider_5
        function SliceSlider_5ValueChanged(app, event)
            app.SliceSlider_5.Value = min(round(event.Value),size(app.UIDisplay.currentTensor,3));
            app.SliceSlider_5Label.Text = [app.UIDisplay.ylabel num2str(app.SliceSlider_5.Value)];
        end

        % Button pushed function: TVReconButton
        function TVReconButtonPushed(app, event)
            app.ReconOptions.tv.maxIter = app.MaxIterationsEditField.Value;
            app.ReconOptions.flagAutoLambdaTV = app.AutolambdaCheckBox_2.Value;
            app.ReconOptions.tv.lambda = app.lambdaEditField.Value;
            app.ReconOptions.tv.alpha  = 1;
            app.ReconOptions.tv.flagContinue = 0;
            app.ReconOptions.dispSlice = floor(app.Params.Nz/2) + 1;

            app.Lamp_13.Color = [1 0 0];
            updateStatus(app, ' TV recon ...');
            [app.ReconOptions,app.SpatialCoeff] = tvRecon3D_aniso(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff);

            close all;
            
            app.DropDown_4.Enable = 'on';
            app.DropDown_4.Value  = app.DropDown_4.Items{2};
            DropDown_4ValueChanged(app);
            
            app.lambdaEditField.Value = double(app.ReconOptions.tv.lambda);
            app.Lamp_13.Color = [0 1 0];
            updateSuccessStatus(app, ' TV recon ... done.');
            
            app.FitParams = createFitParams(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff,app.FitParams);
            app.SlicesEditField.Value = ['1:' num2str(app.FitParams.Nzdisp)];
            app.StartFittingButton.Enable = 'on';
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            temp = app.SpatialCoeff;
            value = app.DropDown_4.Value;
            if strcmp(value, 'Tensor')
                temp.U = app.SpatialCoeff.U_tensor;
            end
            
            dim = str2double(app.DropDown_5.Value);
            if dim == 2
                displayIdx = [app.TimeSlider_2.Value 1 1];
            elseif dim == 3
                displayIdx = [floor(app.Params.linesPerShot/2) app.TimeSlider_2.Value 1];
            else 
                displayIdx = [floor(app.Params.linesPerShot/2) 1 app.TimeSlider_2.Value];
            end
            
            app.ReconstructedImages = genImageTensor(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,temp,app.ReconstructedImages,dim,displayIdx(1),displayIdx(2),displayIdx(3));
            app.UIDisplay.currentTensor = prepImageForAxes(app,imageOrientLPS(app.ReconstructedImages.reconTensor,app.Params));
            updateTensorAxes(app);
        end

        % Button pushed function: PlayMovieButton_3
        function PlayMovieButton_3Pushed(app, event)
            displayNz = app.SliceSlider_5.Value;
            scalar  = app.WWSlider_3.Value;
            imgDisp = app.UIDisplay.currentTensor(:,:,displayNz,:);
            if size(app.UIDisplay.currentTensor,4) > 15
                fps = 20;
            else
                fps = floor(size(app.UIDisplay.currentTensor,4)/2);
            end
            implayZoom(imgDisp/scalar,fps);
        end

        % Value changing function: WWSlider_3
        function WWSlider_3ValueChanging(app, event)
            app.WWSlider_3.Value = event.Value;
            updateTensorAxes(app);
        end

        % Button pushed function: SaveWorkspaceButton
        function SaveWorkspaceButtonPushed(app, event)
            updateStatus(app, ' Saving workspace ...');
            app.SaveWorkspaceButton.BackgroundColor = [1 0 0]; drawnow;
            if isfield(app.TemporalBasis,'Phi')
                app.FitParams = createFitParams(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff,app.FitParams);
            end
            Params = app.Params;
            ReconOptions = app.ReconOptions;
            DataArray = app.DataArray;
            TemporalBasis = app.TemporalBasis;
            SpatialCoeff = app.SpatialCoeff;
            TwixObj = app.TwixObj;
            FitParams = app.FitParams;
            ReconstructedImages = app.ReconstructedImages;
            
            ReconOptions.flagCommandLine = true;
            
            saveMultitaskingWorkspace;
            %save(app.Params.fileString,'Params','ReconOptions','DataArray','TemporalBasis','SpatialCoeff','TwixObj','FitParams','-v7.3'); 

            if strcmp(app.SaveFittingResultButton.Enable,'on')
                SaveFittingResultButtonPushed(app, event);
            end
            app.SaveWorkspaceButton.BackgroundColor = [0.96 0.96 0.96];
            updateSuccessStatus(app, ' Workspace saved.');
        end

        % Button pushed function: ExportDICOMButton
        function ExportDICOMButtonPushed(app, event)
            app.ExportDICOMButton.BackgroundColor = [1 0 0]; drawnow;
            updateStatus(app, ' Exporting dicom images ...');
%             filePrefix = sprintf('MID%d_%s',app.TwixObj.hdr.Meas.MeasUID,app.TwixObj.hdr.Dicom.tProtocolName);
% 
            if strcmp(app.TabGroup.SelectedTab.Title, 'Fitting')
%                 prompt = {'Dicom file name:'};
%                 dlgtitle = 'Export parametric maps to Dicom';
%                 dims = [1 100];
%                 definput = {filePrefix};
%                 fileNameNew = inputdlg(prompt,dlgtitle,dims,definput);
% 
%                 updateStatus(app, [' Exporting ' [fileNameNew{1} '_T1map'] '.dcm ...']);
%                 genDicomfromSiemens((app.FitResult.T1map*1000), app.TwixObj, app.ReconOptions.interpFactor, 0, [fileNameNew{1} '_T1map'], 1, 1, 1, 1000/app.ReconOptions.cbins);
%                 if isfield(app.FitResult,'T2map')
%                     updateStatus(app, [' Exporting ' [fileNameNew{1} '_T2map'] '.dcm ...']);
%                     genDicomfromSiemens((app.FitResult.T2map*1000), app.TwixObj, app.ReconOptions.interpFactor, 0, [fileNameNew{1} '_T2map'], 1, 1, 1, 1000/app.ReconOptions.cbins);
%                 end
                if isfield(app.FitResult,'T1map') && isfield(app.FitResult,'T2map')
                    exportFolder = qMATCH_dicomwrite(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff,app.FitResult);
                    app.ExportDICOMButton.BackgroundColor = [0.96 0.96 0.96]; drawnow;
                    updateSuccessStatus(app, [' Dicom saved to ' exportFolder ' .']);
                else
                    updateErrorStatus(app,' T1/T2 Maps not available. Please run fitting first!');
                end
            else
                switch app.TabGroup.SelectedTab.Title
                    case 'Preprocessing'
                        imgDisp = app.UIDisplay.currentDisplayPreproc;
                        if strcmp(app.DropDown.Value,'Real-time Image')
                            fileName = '_realTime';
                        else
                            fileName = ['_' app.DropDown.Value];
                        end
                    case 'Motion Compensation'
                        imgDisp = app.UIDisplay.currentBinning;
                        if strcmp(app.DropDown_2.Value,'Resp Motion')
                            fileName = '_binResp';
                        else
                            fileName = '_binCard';
                        end
                    case 'Tensor'
                        imgDisp = app.UIDisplay.currentTensor;
                        if strcmp(app.DropDown_5.Value,'2') 
                            fileName = ['_' app.DropDown_4.Value '_Recovery'];
                        elseif strcmp(app.DropDown_5.Value,'3')
                            fileName = ['_' app.DropDown_4.Value '_Cine'];
                        else
                            fileName = ['_' app.DropDown_4.Value '_Resp'];
                        end
                end
                imgDisp = revertImageOrient(app,imgDisp);

                prompt = {'Dicom file name:'};
                dlgtitle = 'Export Dicom';
                dims = [1 100];
                definput = {[filePrefix fileName]};
                fileNameNew = inputdlg(prompt,dlgtitle,dims,definput);
                
                updateStatus(app, [' Exporting ' fileNameNew{1} '.dcm ...']);
                genDicomfromSiemens(imgDisp, app.TwixObj, app.ReconOptions.interpFactor, 1, fileNameNew{1}, 1);
                app.ExportDICOMButton.BackgroundColor = [0.96 0.96 0.96]; drawnow;
                updateSuccessStatus(app, [' Dicom saved to ' fileparts(app.Params.filePath) '/dicom/ .']);
            end
            
        end

        % Value changed function: NkyEditField
        function NkyEditFieldValueChanged(app, event)
            app.Params.Ntrajs = app.NkyEditField.Value;
        end

        % Value changed function: FlipAngleEditField
        function FlipAngleEditFieldValueChanged(app, event)
            value = regexp(app.FlipAngleEditField.Value,'[ ,;]','split');
            app.Params.numFA = numel(value);
            app.Params.flipAngleArray = [];
            for n = 1:app.Params.numFA
                if isfinite(str2double(value{n}))
                    app.Params.flipAngleArray = str2double(value{n});
                else
                    app.FlipAngleEditField.Value = num2str(app.TwixObj.hdr.MeasYaps.adFlipAngleDegree{1});
                    app.Params.numFA = 1;
                    break;
                end
            end
            if app.Params.numFA > 0 && (app.Params.numIRns + app.Params.numT2prep + app.Params.numT1rhoPrep) > 0
                app.Params.moduleLength = lcm(app.Params.numFA,(app.Params.numIRns + app.Params.numT2prep + app.Params.numT1rhoPrep));
            else
                app.Params.moduleLength = max(max(app.Params.numFA,(app.Params.numIRns + app.Params.numT2prep + app.Params.numT1rhoPrep)),1);
            end
            if app.Params.numIRns > 0 && app.Params.numT2prep == 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA == 1
                app.Params.ScanType = 'IR';
            elseif app.Params.numIRns > 0 && app.Params.numT2prep == 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA > 1
                app.Params.ScanType = 'IR_VFA';
            elseif app.Params.numT2prep > 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA == 1
                app.Params.ScanType = 'T2IR';
            elseif app.Params.numT2prep > 0 && app.Params.numT1rhoPrep == 0 && app.Params.numFA > 1
                app.Params.ScanType = 'T2IR_VFA';
            end 
            app.ScanTypeEditField.Value = app.Params.ScanType;
            app.Lamp.Color = [0.8 0.8 0.8];
            app.Lamp_9.Color = [0.8 0.8 0.8];
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title,'Preprocessing')
                app.IREditField.Editable = 'on';
                app.T2IRmsEditField.Editable = 'on';
                app.T1rhoprepmsEditField.Editable = 'on';
                app.FlipAngleEditField.Editable = 'on';
                app.SGBlockEditField.Editable = 'on';
                app.LinesperShotEditField.Editable = 'on';
                app.MBFactorEditField.Editable = 'on';
            else
                app.IREditField.Editable = 'off';
                app.T2IRmsEditField.Editable = 'off';
                app.T1rhoprepmsEditField.Editable = 'off';
                app.FlipAngleEditField.Editable = 'off';
                app.SGBlockEditField.Editable = 'off';
                app.LinesperShotEditField.Editable = 'off';
                app.MBFactorEditField.Editable = 'off';
            end
        end

        % Button pushed function: MoCoButton
        function MoCoButtonPushed(app, event)
            app.Lamp_14.Color = [1 0 0];
            updateStatus(app,' Respiratory motion correction ...');
            
            [app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff] = mocoResp(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff);
            
            app.UIDisplay.xlabelBin = 'Bin # ';
            app.BinSliderLabel.Text = [app.UIDisplay.xlabelBin '1'];
            app.SliceSlider_4Label.Text = [app.UIDisplay.ylabel '1'];
            app.UIDisplay.currentBinning = imageOrientLPS(app.DataArray.binsRespMean,app.Params);
            updateBinningAxesImage(app);
            
            app.RespBinningButton.Enable = 'off';
            app.MoCoButton.Enable = 'off';
            app.RunAllButton_3.Enable = 'off';
            app.Lamp_14.Color = [0 1 0];
            updateStatus(app,' Respiratory motion correction ... done.');
        end

        % Value changed function: NewCoilEditField
        function NewCoilEditFieldValueChanged(app, event)
            app.NewCoilEditField.Value = min(app.Params.Ncoils,app.NewCoilEditField.Value);
            if isfield(app.DataArray,'coilEnergycumsum')
                app.EnergyEditField.Value  = double(app.DataArray.coilEnergycumsum(app.NewCoilEditField.Value)*100);
            end
            app.ReconOptions.minNewCoils   = app.NewCoilEditField.Value;
            app.ReconOptions.mincoilEnergy = app.EnergyEditField.Value/100.0 - 0.005;
        end

        % Value changed function: EnergyEditField
        function EnergyEditFieldValueChanged(app, event)
            app.EnergyEditField.Value  = min(100,app.EnergyEditField.Value);
            if isfield(app.DataArray,'coilEnergycumsum')
                app.NewCoilEditField.Value = find(app.DataArray.coilEnergycumsum*100>app.EnergyEditField.Value,1);
            end
            app.ReconOptions.minNewCoils   = app.NewCoilEditField.Value;
            app.ReconOptions.mincoilEnergy = app.EnergyEditField.Value/100.0 - 0.005;
        end

        % Value changed function: BlackBloodButton
        function BlackBloodButtonValueChanged(app, event)
            value = app.BlackBloodButton.Value;
            if value                
                app.DataArray.bIdx = getBlackBloodIdx(app.Params,app.ReconOptions,app.TemporalBasis,app.SpatialCoeff);
            end

            if app.UIDisplay.currentTensorView == 2 && value 
                %displayIdx = [app.TimeSlider_2.Value 1 1];
                app.UIDisplay.currentTensorView = 3;
                app.DropDown_5.Value = '3';
%             elseif app.UIDisplay.currentTensorView == 3
%                 displayIdx = [floor(app.Params.linesPerShot/2) app.TimeSlider_2.Value 1];
%             else 
%                 displayIdx = [floor(app.Params.linesPerShot/2) 1 app.TimeSlider_2.Value];
            end
            DropDown_5ValueChanged(app,event);
            
            %app.DataArray = genImageTensor(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff,app.UIDisplay.currentTensorView,displayIdx(1),displayIdx(2),displayIdx(3),value);
            %app.UIDisplay.currentTensor = app.ReconstructedImages.reconTensor;

            %updateTensorAxesImage(app);
        end

        % Callback function
        function FittingstartatframeEditFieldValueChanged(app, event)
            value = app.FittingstartatframeEditField.Value;
            if value > app.Params.linesPerShot
                app.FittingstartatframeEditField.Value = app.Params.linesPerShot;
            end
            app.FitParams.imstart = app.FittingstartatframeEditField.Value;
            app.SkipframeEditField.Limits(2) = min(app.SkipframeEditField.Limits(2),app.Params.linesPerShot-app.FitParams.imstart);
        end

        % Callback function
        function CardiacPhaseEditFieldValueChanged(app, event)
            value = app.CardiacPhaseEditField.Value;
            try
                evalstr = ['app.FitParams.cphases = ' value ';'];
                eval(evalstr);
                app.FitParams.cphases = round(app.FitParams.cphases);
                app.FitParams.cphases = unique(app.FitParams.cphases);
                for n = numel(app.FitParams.cphases):-1:1
                    if app.FitParams.cphases(n) > size(app.FitParams.Phi,3) || app.FitParams.cphases(n) < 1
                        app.FitParams.cphases(n) = [];
                    end
                end
                updateStatus(app, ['Cardiac phases set to ' value])
            catch
                updateErrorStatus(app, 'Cardiac phases not valid!')
            end
        end

        % Callback function
        function RespPhaseEditFieldValueChanged(app, event)
            value = app.RespPhaseEditField.Value;
            try
                evalstr = ['app.FitParams.rphase = ' value ';'];
                eval(evalstr);
                app.FitParams.rphase = round(app.FitParams.rphase);
                app.FitParams.rphase = unique(app.FitParams.rphase);
                for n = numel(app.FitParams.rphase):-1:1
                    if app.FitParams.rphase(n) > size(app.FitParams.Phi,4) || app.FitParams.rphase(n) < 1
                        app.FitParams.rphase(n) = [];
                    end
                end
            catch
                updateErrorStatus(app, 'Cardiac phases not valid!')
            end
        end

        % Callback function
        function SlicesEditFieldValueChanged(app, event)
            value = app.SlicesEditField.Value;
            try
                evalstr = ['app.FitParams.fitSlice = ' value ';'];
                eval(evalstr);
                app.FitParams.fitSlice = round(app.FitParams.fitSlice);
                app.FitParams.fitSlice = unique(app.FitParams.fitSlice);
                for n = numel(app.FitParams.fitSlice):-1:1
                    if app.FitParams.fitSlice(n) > app.Params.Nz || app.FitParams.fitSlice(n) < 1
                        app.FitParams.fitSlice(n) = [];
                    end
                end
            catch
                updateErrorStatus(app, 'Slice numbers not valid!')
            end
        end

        % Button pushed function: StartFittingButton
        function StartFittingButtonPushed(app, event)
            app.Lamp_15.Color = [1 0 0];
            
            app.FitParams.imstart = app.FittingstartatframeEditField.Value;
            app.FitParams.imskip  = app.SkipframeEditField.Value;
            
            RespPhaseEditFieldValueChanged(app, event);
            CardiacPhaseEditFieldValueChanged(app, event);
            SlicesEditFieldValueChanged(app, event);
            
            if app.Level1Button.Value
                app.FitParams.flagLargeROI = 0;
            elseif app.Level2Button.Value
                app.FitParams.flagLargeROI = 1;
            elseif app.Level3Button.Value
                app.FitParams.flagLargeROI = 2;
            elseif app.NoMaskButton.Value   
                app.FitParams.flagLargeROI = 3;
            elseif app.BETButton.Value
                app.FitParams.flagLargeROI = 4;
            end

            % -- fitting method
            if app.ConstantbetaButton.Value
                app.FitParams.ChooseConst = 4;
                app.EditField16.Value = app.B1betaEditField.Value;
                app.EditField17.Value = app.B1betaEditField.Value;
            elseif app.SingleInvEfficiencyButton.Value
                app.FitParams.ChooseConst = 2;
            else
                app.FitParams.ChooseConst = 0;
            end
            
            % -- initial values
            app.FitParams.initT1     = app.T1EditField_2.Value/1000;
            app.FitParams.initT2     = app.T2EditField_2.Value/1000;
            app.FitParams.initBIR    = app.InvEfficiencyEditField.Value;
            app.FitParams.initBT2    = app.FitParams.initBIR;
            app.FitParams.initGlobalBeta = app.B1betaEditField.Value;
            % app.FitParams.B1_coeff = 1;
            
            % -- lower & upper bounds
            app.FitParams.minT1 = app.EditField7.Value/1000;
            app.FitParams.maxT1 = app.EditField8.Value/1000;
            app.FitParams.minT2 = app.EditField10.Value/1000;
            app.FitParams.maxT2 = app.EditField11.Value/1000;
            app.FitParams.minBIR = app.EditField13.Value;
            app.FitParams.maxBIR = app.EditField14.Value;
            app.FitParams.minBT2 = app.EditField13.Value;
            app.FitParams.maxBT2 = app.EditField14.Value;
            app.FitParams.minBeta = app.EditField16.Value;      
            app.FitParams.maxBeta = app.EditField17.Value; 
            
            % -- 2-step fitting for VFA data
            if app.stepfittingCheckBox.Value == true
                app.FitParams.flag2stepFitting = true;
            else 
                app.FitParams.flag2stepFitting = false;
            end
            
            % -- Do fitting
            app.FitResult = paramFit(app.FitParams);

            close all;
            
            if contains(app.Params.ScanType, 'T2IR')
                app.DropDown_6.Enable = 'on';
            end
            app.DropDown_6.Value = 'T1';
            
            % -- display maps
            app.PhaseSliderLabel.Text = 'Phase #1';
            app.PhaseSlider.Enable = 'on';
            app.SliceSlider_6Label.Text = 'Slice #1';
            app.SliceSlider_6.Enable = 'on';
            app.UIDisplay.currentMap = imageOrientLPS(app.FitResult.T1map,app.Params);
            app.UIDisplay.currentMap = app.UIDisplay.currentMap / app.FitParams.maxT1;
            updateMapAxesImage(app);
            
            app.CheckFittingResultButton.Enable = 'on';
            app.SaveFittingResultButton.Enable  = 'on';
            app.PlayMovieButton_4.Enable = 'on';
            app.SaveGifButton_4.Enable = 'on';
            
            app.Lamp_15.Color = [0 1 0];
        end

        % Value changing function: PhaseSlider
        function PhaseSliderValueChanging(app, event)
            app.PhaseSlider.Value = min(round(event.Value),size(app.UIDisplay.currentMap,4));
            app.PhaseSliderLabel.Text = ['Phase #' num2str(app.PhaseSlider.Value)];
            updateMapAxes(app);
        end

        % Value changed function: PhaseSlider
        function PhaseSliderValueChanged(app, event)
            app.PhaseSlider.Value = min(round(event.Value),size(app.UIDisplay.currentMap,4));
            app.PhaseSliderLabel.Text = ['Phase #' num2str(app.PhaseSlider.Value)];
        end

        % Value changing function: SliceSlider_6
        function SliceSlider_6ValueChanging(app, event)
            app.SliceSlider_6.Value = min(round(event.Value),size(app.UIDisplay.currentMap,3));
            app.SliceSlider_6Label.Text = ['Slice #' num2str(app.SliceSlider_6.Value)];
            updateMapAxes(app);
        end

        % Value changed function: SliceSlider_6
        function SliceSlider_6ValueChanged(app, event)
            app.SliceSlider_6.Value = min(round(event.Value),size(app.UIDisplay.currentMap,3));
            app.SliceSlider_6Label.Text = ['Slice #' num2str(app.SliceSlider_6.Value)];
        end

        % Button pushed function: CheckFittingResultButton
        function CheckFittingResultButtonPushed(app, event)
%             figure;imagesc(wmedfilt2(app.FitResult.T1map(:,:,app.SliceSlider_6.Value,app.PhaseSlider.Value)*1000),[0 3000]);axis equal tight;colormap(warmmetal);title('T1 map');  
%             if isfield(app.FitResult,'T2map')
%                 figure;imagesc(wmedfilt2(app.FitResult.T2map(:,:,app.SliceSlider_6.Value,app.PhaseSlider.Value)*1000),[0 200]); axis equal tight;colormap(warmmetal);title('T2 map');
%             end
%             app.ReconstructedImages = genImageTensor(app.Params,app.ReconOptions,app.DataArray,app.TemporalBasis,app.SpatialCoeff,app.ReconstructedImages,2,1,app.FitResult.fitParams.cphases(app.PhaseSlider.Value),app.FitResult.fitParams.rphase,0,1);
%             %curve = plotSelectedVoxel(app.ReconstructedImages.reconTensor,[],[],app.SliceSlider_6.Value,app.FitResult,app.PhaseSlider.Value);
            curve = plotFitResult(app.FitResult,app.SliceSlider_6.Value,app.PhaseSlider.Value);
        end

        % Button pushed function: PlayMovieButton_4
        function PlayMovieButton_4Pushed(app, event)
            map = app.UIDisplay.currentMap(:,:,app.SliceSlider_6.Value,:);
            map_color = zeros(size(repmat(map(:,:,1,:),[1 1 3 1])));
            for n = 1:size(map_color,4)
                map_color(:,:,:,n) = ind2rgb(round(255*(wmedfilt2(map(:,:,1,n)))),warmmetal(256));
            end
            if size(map_color,4) == 1
                map_color(:,:,:,2) = map_color;
            end
            implayZoom(map_color,size(map,4));
        end

        % Button pushed function: SaveGifButton_4
        function SaveGifButton_4Pushed(app, event)
            map = app.UIDisplay.currentMap(:,:,app.SliceSlider_6.Value,:);
            temp = wmedfilt2(map(:,:,1,:));
%             if strcmp(app.DropDown_6.Value,'T1')
%                 filename = [app.FitResult.fitParams.filePath '_T1map.gif'];
%             elseif strcmp(app.DropDown_6.Value,'T2')
%                 filename = [app.FitResult.fitParams.filePath '_T2map.gif'];
%             else
%                 filename = 'map.gif';
%             end
%             fps = app.ReconOptions.cbins;
%             imwrite(temp(:,:,:,1),warmmetal(256),filename,'gif','LoopCount',inf','DelayTime',1/fps);
%             for n = 2:size(temp,4)
%                 imwrite(temp(:,:,:,n),warmmetal(256),filename,'gif','WriteMode','append','delaytime',1/fps);
%             end
            
            [~,filePrefix] = fileparts(app.FitResult.fitParams.filePath);
            if strcmp(app.DropDown_6.Value,'T1')
                fileName = [filePrefix '_T1map.gif'];
            elseif strcmp(app.DropDown_6.Value,'T2')
                fileName = [filePrefix '_T2map.gif'];
            else
                fileName = 'map.gif';
            end
            saveGif(temp,app.FitResult.fitParams.filePath,fileName,size(temp,4),warmmetal(256));
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            value = app.DropDown_6.Value;
            if strcmp(value, 'T1')
                app.UIDisplay.currentMap = prepImageForAxes(app,imageOrientLPS(app.FitResult.T1map,app.Params));
                app.UIDisplay.currentMap = app.UIDisplay.currentMap / app.FitParams.maxT1;
            elseif strcmp(value, 'T2')
                app.UIDisplay.currentMap = prepImageForAxes(app,imageOrientLPS(app.FitResult.T2map,app.Params));
                app.UIDisplay.currentMap = app.UIDisplay.currentMap / app.FitParams.maxT2;
            end

            updateMapAxes(app);
        end

        % Button pushed function: RunAllButton_2
        function RunAllButton_2Pushed(app, event)
            SetupTensorParametersButtonPushed(app, event);
            TensorSubspaceButtonPushed(app, event);
            TVReconButtonPushed(app, event);
        end

        % Button pushed function: SaveFittingResultButton
        function SaveFittingResultButtonPushed(app, event)
            FitResult = app.FitResult;
            fitResultFileString = [app.FitParams.filePath '/multitasking_FitResult.mat'];
            save(fitResultFileString,'FitResult','-v7.3'); 
            disp('FitResult Saved.')
        end

        % Button pushed function: SaveGifButton
        function SaveGifButtonPushed(app, event)
            displayNz = app.SliceSlider_3.Value;
            scalar  = app.WWSlider.Value;
            [~,filePrefix] = fileparts(app.Params.filePath);
            if strcmp(app.DropDown.Value,'Volume Mean')
                imgDisp = app.UIDisplay.currentDisplayPreproc(:,:,:,1)/scalar;
                fileName = [filePrefix '_fbpComposite.gif'];
                fps = 8;
            elseif strcmp(app.DropDown.Value,'Sensitivity')
                imgDisp = app.UIDisplay.currentDisplayPreproc(:,:,displayNz,:)/scalar;
                fileName = [filePrefix '_SensitivityMaps.gif'];
                fps = 4;
            else
                imgDisp = app.UIDisplay.currentDisplayPreproc(:,:,displayNz,:)/scalar;
                fileName = [filePrefix '_reconRealtime.gif'];
                fps = 20;
            end
            saveGif(imgDisp,app.Params.filePath,fileName,fps);
        end

        % Button pushed function: SaveGifButton_3
        function SaveGifButton_3Pushed(app, event)
            displayNz = app.SliceSlider_5.Value;
            scalar  = app.WWSlider_3.Value;
            imgDisp = app.UIDisplay.currentTensor(:,:,displayNz,:)/scalar;
            [~,filePrefix] = fileparts(app.Params.filePath);
            if strcmp(app.DropDown_4.Value,'Tensor')
                fileName = [filePrefix '_tensor'];
            else
                fileName = [filePrefix '_TV'];
            end
            if strcmp(app.DropDown_5.Value,'2')
                fileName = [fileName '_reconRecovery'];
                fps = 100;
            elseif strcmp(app.DropDown_5.Value,'3')
                fileName = [fileName '_reconCard'];
                fps = size(imgDisp,4);
            else
                fileName = [fileName '_reconResp'];
                fps = size(imgDisp,4)/2;
            end  
            saveGif(imgDisp,app.Params.filePath,fileName,fps);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            [~,~,tempID] = loadLicenseFile([],1);
            tempID = erase(tempID,'_Offline');
            genTempLicenseFile(tempID);
            fprintf('done.\n')
            
            [licenseNr,flagOffline,app.ReconOptions.licenseID] = loadLicenseFile;
            if flagOffline
                licenseNr  = licenseNr/sum(double('multitasking'));
                datestr    = char(datetime(licenseNr(end),'ConvertFrom','datenum','Format','MM/dd/yyyy'));
                Ndays      = licenseNr(end) - datenum(date);
                if Ndays < 0
                    IDtag = ['License ID: ' app.ReconOptions.licenseID(1:end-8) ' - EXPIRED ' datestr];
                else
                    IDtag = ['License ID: ' app.ReconOptions.licenseID(1:end-8) ' - ' datestr];
                end
            else
                IDtag = ['License ID: ' app.ReconOptions.licenseID ' - Online'];
            end
            app.LicenseIDLabel.Text = IDtag;
        end

        % Selection changed function: FitParametersButtonGroup
        function FitParametersButtonGroupSelectionChanged(app, event)
            if app.ConstantbetaButton.Value == 1
                app.EditField16.Value = app.B1betaEditField.Value;
                app.EditField17.Value = app.B1betaEditField.Value;
            else
                app.EditField16.Value = 0.5;
                app.EditField17.Value = 1.0;
            end
        end

        % Button pushed function: RunAllButton_3
        function RunAllButton_3Pushed(app, event)
            RespBinningButtonPushed(app, event);
            CardiacBinningButtonPushed(app, event);
        end

        % Button pushed function: LoadFitParamsButton
        function LoadFitParamsButtonPushed(app, event)
            [fid_file, fid_path] = uigetfile('fitParams.mat');
            fidString  = [fid_path fid_file];
            try
                temp = load(fidString,'FitParams');
                app.FitParams = temp.FitParams;
                app.SlicesEditField.Value = ['1:' num2str(app.FitParams.Nzdisp)];
                app.TotalFramesEditField.Value = app.FitParams.Nseg;
                app.Params = temp.FitParams.params;
                app.ReconOptions = temp.FitParams.reconOptions;
                updateTextFieldFromStruct(app);
                app.NEchoEditField.Value = app.Params.Necho;
                if app.Params.isCartesian
                    app.NkyEditField.Value = app.Params.Ny;
                    app.NkyEditFieldLabel.Text = 'Nky';
                else
                    app.NkyEditField.Value = app.Params.Ntrajs;
                    app.NkyEditFieldLabel.Text = 'Ntrajs';
                end
                app.NkzEditField.Value = app.Params.Nz;
                
                updateSuccessStatus(app,'FitParams loaded.')
            catch
                updateErrorStatus(app,'Load FitParams failed.')
            end
        end

        % Button pushed function: RemoveBinsButton
        function RemoveBinsButtonPushed(app, event)
            binsToRemove = selectBinsToRemove(max(app.DataArray.Ridx),app.DataArray.binsRespMean);
            app.DataArray = removeBins(binsToRemove,app.Params,app.DataArray);

            app.UIDisplay.xlabelBin = 'Bin # ';
            app.BinSliderLabel.Text = [app.UIDisplay.xlabelBin '1'];
            app.SliceSlider_4Label.Text = [app.UIDisplay.ylabel '1'];
            app.UIDisplay.currentBinning = imageOrientLPS(app.DataArray.binsRespMean,app.Params);
            updateBinningAxesImage(app);
            
            app.MoCoButton.Enable = 'off';
            app.RunAllButton_3.Enable = 'off';
            app.Lamp_14.Color = [0 1 0];
            updateStatus(app,' Selected respiratory bins removed.');
        end

        % Button pushed function: RunAllReconFittingButton
        function RunAllReconFittingButtonPushed(app, event)
            RunAllPreprocessingButtonPushed(app, event);
            RunAllButton_3Pushed(app, event);
            RunAllButton_2Pushed(app, event);
            StartFittingButtonPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MultitaskingReconUIFigure = uifigure('Visible', 'off');
            app.MultitaskingReconUIFigure.Name = 'Multitasking Recon';
            app.TabGroup = uitabgroup(app.MultitaskingReconUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.UIAxes = uiaxes(app.PreprocessingTab);
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.PreprocessingTab);
            app.UIAxes_2.XAxisLocation = 'origin';
            app.UIAxes_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.YTickLabel = '';
            app.LoadDataButton = uibutton(app.PreprocessingTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Enable = 'off';
            app.LoadDataButton.Text = 'Load Data';
            app.ScanLengthEditFieldLabel = uilabel(app.PreprocessingTab);
            app.ScanLengthEditFieldLabel.Text = 'Scan Length';
            app.ScanLengthEditField = uieditfield(app.PreprocessingTab, 'text');
            app.ScanLengthEditField.ValueChangedFcn = createCallbackFcn(app, @ScanLengthEditFieldValueChanged, true);
            app.ScanLengthEditField.Value = 'inf';
            app.secLabel = uilabel(app.PreprocessingTab);
            app.secLabel.Text = 'sec';
            app.GradientdelaycorrectionCheckBox = uicheckbox(app.PreprocessingTab);
            app.GradientdelaycorrectionCheckBox.Enable = 'off';
            app.GradientdelaycorrectionCheckBox.Text = ' Gradient delay correction';
            app.EddycurrentcompensationCheckBox = uicheckbox(app.PreprocessingTab);
            app.EddycurrentcompensationCheckBox.Enable = 'off';
            app.EddycurrentcompensationCheckBox.Text = ' Eddy current compensation';
            app.SetupTrajectoryButton = uibutton(app.PreprocessingTab, 'push');
            app.SetupTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetupTrajectoryButtonPushed, true);
            app.SetupTrajectoryButton.Enable = 'off';
            app.SetupTrajectoryButton.Text = 'Setup Trajectory';
            app.EstimateSensitivityMapButton = uibutton(app.PreprocessingTab, 'push');
            app.EstimateSensitivityMapButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateSensitivityMapButtonPushed, true);
            app.EstimateSensitivityMapButton.Enable = 'off';
            app.EstimateSensitivityMapButton.Text = {'Estimate '; 'Sensitivity Map'};
            app.MethodButtonGroup = uibuttongroup(app.PreprocessingTab);
            app.MethodButtonGroup.Title = 'Method';
            app.CBDButton = uiradiobutton(app.MethodButtonGroup);
            app.CBDButton.Text = 'CBD';
            app.CBDButton.Value = true;
            app.WalshButton = uiradiobutton(app.MethodButtonGroup);
            app.WalshButton.Text = 'Walsh';
            app.ESPIRiTButton = uiradiobutton(app.MethodButtonGroup);
            app.ESPIRiTButton.Text = 'ESPIRiT';
            app.RealtimeReconButton = uibutton(app.PreprocessingTab, 'push');
            app.RealtimeReconButton.ButtonPushedFcn = createCallbackFcn(app, @RealtimeReconButtonPushed, true);
            app.RealtimeReconButton.Enable = 'off';
            app.RealtimeReconButton.Text = 'Real-time Recon';
            app.Label_2 = uilabel(app.PreprocessingTab);
            app.Label_2.Text = '';
            app.Lamp = uilamp(app.PreprocessingTab);
            app.Lamp_2Label = uilabel(app.PreprocessingTab);
            app.Lamp_2Label.Text = '';
            app.Lamp_2 = uilamp(app.PreprocessingTab);
            app.Lamp_4Label = uilabel(app.PreprocessingTab);
            app.Lamp_4Label.Text = '';
            app.Lamp_4 = uilamp(app.PreprocessingTab);
            app.Lamp_5Label = uilabel(app.PreprocessingTab);
            app.Lamp_5Label.Text = '';
            app.Lamp_5 = uilamp(app.PreprocessingTab);
            app.CoilCompressionButton = uibutton(app.PreprocessingTab, 'push');
            app.CoilCompressionButton.ButtonPushedFcn = createCallbackFcn(app, @CoilCompressionButtonPushed, true);
            app.CoilCompressionButton.Enable = 'off';
            app.CoilCompressionButton.Text = {'Coil'; 'Compression'};
            app.Lamp_6Label = uilabel(app.PreprocessingTab);
            app.Lamp_6Label.Text = '';
            app.Lamp_6 = uilamp(app.PreprocessingTab);
            app.TimeSliderLabel = uilabel(app.PreprocessingTab);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.PreprocessingTab);
            app.TimeSlider.Limits = [1 512];
            app.TimeSlider.MajorTicks = [];
            app.TimeSlider.MajorTickLabels = {};
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.MinorTicks = [];
            app.TimeSlider.Enable = 'off';
            app.TimeSlider.Value = 1;
            app.SliceSlider_3Label = uilabel(app.PreprocessingTab);
            app.SliceSlider_3Label.Text = 'Slice';
            app.SliceSlider_3 = uislider(app.PreprocessingTab);
            app.SliceSlider_3.Limits = [1 96];
            app.SliceSlider_3.MajorTicks = [];
            app.SliceSlider_3.MajorTickLabels = {};
            app.SliceSlider_3.ValueChangedFcn = createCallbackFcn(app, @SliceSlider_3ValueChanged, true);
            app.SliceSlider_3.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_3ValueChanging, true);
            app.SliceSlider_3.MinorTicks = [];
            app.SliceSlider_3.Enable = 'off';
            app.SliceSlider_3.Value = 1;
            app.NewCoilEditFieldLabel = uilabel(app.PreprocessingTab);
            app.NewCoilEditFieldLabel.Text = 'New Coil #';
            app.NewCoilEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.NewCoilEditField.Limits = [1 48];
            app.NewCoilEditField.ValueDisplayFormat = '%.0f';
            app.NewCoilEditField.ValueChangedFcn = createCallbackFcn(app, @NewCoilEditFieldValueChanged, true);
            app.NewCoilEditField.Value = 15;
            app.EnergyEditFieldLabel = uilabel(app.PreprocessingTab);
            app.EnergyEditFieldLabel.Text = 'Energy';
            app.EnergyEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.EnergyEditField.Limits = [50 100.01];
            app.EnergyEditField.ValueDisplayFormat = '%3.2f';
            app.EnergyEditField.ValueChangedFcn = createCallbackFcn(app, @EnergyEditFieldValueChanged, true);
            app.EnergyEditField.Value = 99.5;
            app.RunAllPreprocessingButton = uibutton(app.PreprocessingTab, 'push');
            app.RunAllPreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllPreprocessingButtonPushed, true);
            app.RunAllPreprocessingButton.Enable = 'off';
            app.RunAllPreprocessingButton.Text = 'Run All Preprocessing';
            app.Label_3 = uilabel(app.PreprocessingTab);
            app.Label_3.Text = '%';
            app.DropDownLabel = uilabel(app.PreprocessingTab);
            app.DropDownLabel.Enable = 'off';
            app.DropDownLabel.Text = '';
            app.DropDown = uidropdown(app.PreprocessingTab);
            app.DropDown.Items = {'Volume Mean'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Volume Mean';
            app.FlipUDButton = uibutton(app.PreprocessingTab, 'push');
            app.FlipUDButton.ButtonPushedFcn = createCallbackFcn(app, @FlipUDButtonPushed, true);
            app.FlipUDButton.Enable = 'off';
            app.FlipUDButton.Text = 'FlipUD';
            app.FlipLRButton = uibutton(app.PreprocessingTab, 'push');
            app.FlipLRButton.ButtonPushedFcn = createCallbackFcn(app, @FlipLRButtonPushed, true);
            app.FlipLRButton.Enable = 'off';
            app.FlipLRButton.Text = 'FlipLR';
            app.RotateButton = uibutton(app.PreprocessingTab, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Enable = 'off';
            app.RotateButton.Text = 'Rotate';
            app.WWSliderLabel = uilabel(app.PreprocessingTab);
            app.WWSliderLabel.Text = 'WW';
            app.WWSlider = uislider(app.PreprocessingTab);
            app.WWSlider.Limits = [0 2];
            app.WWSlider.MajorTicks = [];
            app.WWSlider.MajorTickLabels = {};
            app.WWSlider.ValueChangingFcn = createCallbackFcn(app, @WWSliderValueChanging, true);
            app.WWSlider.MinorTicks = [];
            app.WWSlider.Enable = 'off';
            app.WWSlider.Value = 1;
            app.Label_4 = uilabel(app.PreprocessingTab);
            app.Label_4.Text = ' ';
            app.RankEditFieldLabel = uilabel(app.PreprocessingTab);
            app.RankEditFieldLabel.Text = 'Rank';
            app.RankEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.RankEditField.Limits = [1 100];
            app.RankEditField.ValueDisplayFormat = '%.0f';
            app.RankEditField.Value = 6;
            app.Lamp_9Label = uilabel(app.PreprocessingTab);
            app.Lamp_9Label.Text = '';
            app.Lamp_9 = uilamp(app.PreprocessingTab);
            app.GeneratecurvePhiButton = uibutton(app.PreprocessingTab, 'push');
            app.GeneratecurvePhiButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratecurvePhiButtonPushed, true);
            app.GeneratecurvePhiButton.Enable = 'off';
            app.GeneratecurvePhiButton.Text = 'Generate curvePhi';
            app.cLEditFieldLabel = uilabel(app.PreprocessingTab);
            app.cLEditFieldLabel.Text = 'cL';
            app.cLEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.cLEditField.Limits = [1 100];
            app.cLEditField.ValueDisplayFormat = '%.0f';
            app.cLEditField.Value = 12;
            app.PlayMovieButton = uibutton(app.PreprocessingTab, 'push');
            app.PlayMovieButton.ButtonPushedFcn = createCallbackFcn(app, @PlayMovieButtonPushed, true);
            app.PlayMovieButton.Enable = 'off';
            app.PlayMovieButton.Text = 'Play Movie';
            app.NewFOVroFOVEditFieldLabel = uilabel(app.PreprocessingTab);
            app.NewFOVroFOVEditFieldLabel.Text = 'New FOVro = FOV/';
            app.NewFOVroFOVEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.NewFOVroFOVEditField.Limits = [1 4];
            app.NewFOVroFOVEditField.Value = 1;
            app.T1EditFieldLabel = uilabel(app.PreprocessingTab);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.T1EditField.Limits = [50 5000];
            app.T1EditField.ValueDisplayFormat = '%.0f';
            app.T1EditField.Value = 500;
            app.EditFieldLabel = uilabel(app.PreprocessingTab);
            app.EditFieldLabel.Text = '~';
            app.EditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.EditField.Limits = [50 10000];
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.Value = 2500;
            app.T2EditFieldLabel = uilabel(app.PreprocessingTab);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.T2EditField.Limits = [10 100];
            app.T2EditField.ValueDisplayFormat = '%.0f';
            app.T2EditField.Value = 10;
            app.EditField_2Label = uilabel(app.PreprocessingTab);
            app.EditField_2Label.Text = '~';
            app.EditField_2 = uieditfield(app.PreprocessingTab, 'numeric');
            app.EditField_2.Limits = [10 5000];
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_2.Value = 100;
            app.SaveGifButton = uibutton(app.PreprocessingTab, 'push');
            app.SaveGifButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGifButtonPushed, true);
            app.SaveGifButton.Enable = 'off';
            app.SaveGifButton.Text = 'Save Gif';
            app.RunAllReconFittingButton = uibutton(app.PreprocessingTab, 'push');
            app.RunAllReconFittingButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllReconFittingButtonPushed, true);
            app.RunAllReconFittingButton.Enable = 'off';
            app.RunAllReconFittingButton.Text = 'Run All Recon + Fitting';
            app.MotionCompensationTab = uitab(app.TabGroup);
            app.MotionCompensationTab.Title = 'Motion Compensation';
            app.UIAxes_3 = uiaxes(app.MotionCompensationTab);
            title(app.UIAxes_3, 'Ridx')
            app.UIAxes_3.XTick = [0 1];
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_3.YTick = [0 1];
            app.UIAxes_3.YTickLabel = '';
            app.UIAxes_6 = uiaxes(app.MotionCompensationTab);
            title(app.UIAxes_6, 'Hidx')
            app.UIAxes_6.XTick = [0 1];
            app.UIAxes_6.XTickLabel = '';
            app.UIAxes_6.YTick = [0 1];
            app.UIAxes_6.YTickLabel = '';
            app.UIAxes_6.ZTick = [0 0.5 1];
            app.UIAxes_6.ZTickLabel = '';
            app.UIAxes_7 = uiaxes(app.MotionCompensationTab);
            app.UIAxes_7.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_7.XTickLabel = '';
            app.UIAxes_7.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_7.YTickLabel = '';
            app.UIAxes_8 = uiaxes(app.MotionCompensationTab);
            title(app.UIAxes_8, 'Temporal Basis')
            app.UIAxes_8.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_8.XTickLabel = '';
            app.UIAxes_8.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_8.YTickLabel = '';
            app.UIAxes_9 = uiaxes(app.MotionCompensationTab);
            title(app.UIAxes_9, 'Spectrum (Hz)')
            app.UIAxes_9.XLim = [-2.5 2.5];
            app.UIAxes_9.XTick = [-2 -1 0 1 2];
            app.UIAxes_9.XTickLabel = {'-2'; '-1'; '0'; '1'; '2'};
            app.UIAxes_9.YTickLabel = '';
            app.UIAxes_11 = uiaxes(app.MotionCompensationTab);
            title(app.UIAxes_11, 'Ridx vs Segment Index')
            app.UIAxes_11.XTick = [0 1];
            app.UIAxes_11.XTickLabel = '';
            app.UIAxes_11.YTick = [0 1];
            app.UIAxes_11.YTickLabel = '';
            app.UIAxes_12 = uiaxes(app.MotionCompensationTab);
            title(app.UIAxes_12, 'Hidx vs Segment Index')
            app.UIAxes_12.XTick = [0 1];
            app.UIAxes_12.XTickLabel = '';
            app.UIAxes_12.YTick = [0 1];
            app.UIAxes_12.YTickLabel = '';
            app.DropDown_2Label = uilabel(app.MotionCompensationTab);
            app.DropDown_2Label.Enable = 'off';
            app.DropDown_2Label.Text = '';
            app.DropDown_2 = uidropdown(app.MotionCompensationTab);
            app.DropDown_2.Items = {'Resp Motion', 'Cardiac Motion'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = 'Resp Motion';
            app.DropDown_3Label = uilabel(app.MotionCompensationTab);
            app.DropDown_3Label.Enable = 'off';
            app.DropDown_3Label.Text = '';
            app.DropDown_3 = uidropdown(app.MotionCompensationTab);
            app.DropDown_3.Items = {'1', '2', '3', '4', '5', '6'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Enable = 'off';
            app.DropDown_3.Value = '1';
            app.RespiratoryBinsEditFieldLabel = uilabel(app.MotionCompensationTab);
            app.RespiratoryBinsEditFieldLabel.Text = 'Respiratory Bins';
            app.RespiratoryBinsEditField = uieditfield(app.MotionCompensationTab, 'numeric');
            app.RespiratoryBinsEditField.Limits = [1 8];
            app.RespiratoryBinsEditField.ValueDisplayFormat = '%.0f';
            app.RespiratoryBinsEditField.Value = 1;
            app.RespBinningButton = uibutton(app.MotionCompensationTab, 'push');
            app.RespBinningButton.ButtonPushedFcn = createCallbackFcn(app, @RespBinningButtonPushed, true);
            app.RespBinningButton.Enable = 'off';
            app.RespBinningButton.Text = 'Resp Binning';
            app.CardiacBinningButton = uibutton(app.MotionCompensationTab, 'push');
            app.CardiacBinningButton.ButtonPushedFcn = createCallbackFcn(app, @CardiacBinningButtonPushed, true);
            app.CardiacBinningButton.Enable = 'off';
            app.CardiacBinningButton.Text = 'Cardiac Binning';
            app.Lamp_7Label = uilabel(app.MotionCompensationTab);
            app.Lamp_7Label.Text = '';
            app.Lamp_7 = uilamp(app.MotionCompensationTab);
            app.Lamp_8Label = uilabel(app.MotionCompensationTab);
            app.Lamp_8Label.Text = '';
            app.Lamp_8 = uilamp(app.MotionCompensationTab);
            app.DropDown_7Label = uilabel(app.MotionCompensationTab);
            app.DropDown_7Label.Text = '#';
            app.DropDown_7 = uidropdown(app.MotionCompensationTab);
            app.DropDown_7.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
            app.DropDown_7.Value = '1';
            app.EditROIButton = uibutton(app.MotionCompensationTab, 'push');
            app.EditROIButton.Enable = 'off';
            app.EditROIButton.Text = 'Edit ROI';
            app.ManualROICheckBox = uicheckbox(app.MotionCompensationTab);
            app.ManualROICheckBox.Text = 'Manual ROI';
            app.CardiacBinsEditFieldLabel = uilabel(app.MotionCompensationTab);
            app.CardiacBinsEditFieldLabel.Text = 'Cardiac Bins';
            app.CardiacBinsEditField = uieditfield(app.MotionCompensationTab, 'numeric');
            app.CardiacBinsEditField.Limits = [1 30];
            app.CardiacBinsEditField.ValueDisplayFormat = '%.0f';
            app.CardiacBinsEditField.Value = 1;
            app.WWSlider_2Label = uilabel(app.MotionCompensationTab);
            app.WWSlider_2Label.Text = 'WW';
            app.WWSlider_2 = uislider(app.MotionCompensationTab);
            app.WWSlider_2.Limits = [0 2];
            app.WWSlider_2.MajorTicks = [];
            app.WWSlider_2.MajorTickLabels = {};
            app.WWSlider_2.ValueChangingFcn = createCallbackFcn(app, @WWSlider_2ValueChanging, true);
            app.WWSlider_2.MinorTicks = [];
            app.WWSlider_2.Enable = 'off';
            app.WWSlider_2.Value = 1;
            app.SliceSlider_4Label = uilabel(app.MotionCompensationTab);
            app.SliceSlider_4Label.Text = 'Slice';
            app.SliceSlider_4 = uislider(app.MotionCompensationTab);
            app.SliceSlider_4.Limits = [1 96];
            app.SliceSlider_4.MajorTicks = [];
            app.SliceSlider_4.MajorTickLabels = {};
            app.SliceSlider_4.ValueChangedFcn = createCallbackFcn(app, @SliceSlider_4ValueChanged, true);
            app.SliceSlider_4.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_4ValueChanging, true);
            app.SliceSlider_4.MinorTicks = [];
            app.SliceSlider_4.Enable = 'off';
            app.SliceSlider_4.Value = 1;
            app.FlipRidxButton = uibutton(app.MotionCompensationTab, 'push');
            app.FlipRidxButton.ButtonPushedFcn = createCallbackFcn(app, @FlipRidxButtonPushed, true);
            app.FlipRidxButton.Enable = 'off';
            app.FlipRidxButton.Text = 'Flip Ridx';
            app.SetasDiastoleButton = uibutton(app.MotionCompensationTab, 'push');
            app.SetasDiastoleButton.ButtonPushedFcn = createCallbackFcn(app, @SetasDiastoleButtonPushed, true);
            app.SetasDiastoleButton.Enable = 'off';
            app.SetasDiastoleButton.Text = 'Set as Diastole';
            app.PlayMovieButton_2 = uibutton(app.MotionCompensationTab, 'push');
            app.PlayMovieButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayMovieButton_2Pushed, true);
            app.PlayMovieButton_2.Enable = 'off';
            app.PlayMovieButton_2.Text = 'Play Movie';
            app.RespBinLabel = uilabel(app.MotionCompensationTab);
            app.RespBinLabel.Text = 'Resp Bin #';
            app.BinSliderLabel = uilabel(app.MotionCompensationTab);
            app.BinSliderLabel.Text = 'Bin';
            app.BinSlider = uislider(app.MotionCompensationTab);
            app.BinSlider.Limits = [1 512];
            app.BinSlider.MajorTicks = [];
            app.BinSlider.MajorTickLabels = {};
            app.BinSlider.ValueChangedFcn = createCallbackFcn(app, @BinSliderValueChanged, true);
            app.BinSlider.ValueChangingFcn = createCallbackFcn(app, @BinSliderValueChanging, true);
            app.BinSlider.MinorTicks = [];
            app.BinSlider.Enable = 'off';
            app.BinSlider.Value = 1;
            app.MoCoButton = uibutton(app.MotionCompensationTab, 'push');
            app.MoCoButton.ButtonPushedFcn = createCallbackFcn(app, @MoCoButtonPushed, true);
            app.MoCoButton.Enable = 'off';
            app.MoCoButton.Text = 'MoCo';
            app.Lamp_14Label = uilabel(app.MotionCompensationTab);
            app.Lamp_14Label.Text = '';
            app.Lamp_14 = uilamp(app.MotionCompensationTab);
            app.ManualROICheckBox_2 = uicheckbox(app.MotionCompensationTab);
            app.ManualROICheckBox_2.Text = 'Manual ROI';
            app.RespFreqEditFieldLabel = uilabel(app.MotionCompensationTab);
            app.RespFreqEditFieldLabel.Text = 'Resp Freq';
            app.RespFreqEditField = uieditfield(app.MotionCompensationTab, 'numeric');
            app.RespFreqEditField.Limits = [1 300];
            app.RespFreqEditField.ValueDisplayFormat = '%.0f';
            app.RespFreqEditField.Value = 3;
            app.EditField_3Label = uilabel(app.MotionCompensationTab);
            app.EditField_3Label.Text = '~';
            app.EditField_3 = uieditfield(app.MotionCompensationTab, 'numeric');
            app.EditField_3.Limits = [3 300];
            app.EditField_3.ValueDisplayFormat = '%.0f';
            app.EditField_3.Value = 50;
            app.CardiacFreqEditFieldLabel = uilabel(app.MotionCompensationTab);
            app.CardiacFreqEditFieldLabel.Text = 'Cardiac Freq';
            app.CardiacFreqEditField = uieditfield(app.MotionCompensationTab, 'numeric');
            app.CardiacFreqEditField.Limits = [5 500];
            app.CardiacFreqEditField.ValueDisplayFormat = '%.0f';
            app.CardiacFreqEditField.Value = 45;
            app.EditField_4Label = uilabel(app.MotionCompensationTab);
            app.EditField_4Label.Text = '~';
            app.EditField_4 = uieditfield(app.MotionCompensationTab, 'numeric');
            app.EditField_4.Limits = [5 500];
            app.EditField_4.ValueDisplayFormat = '%.0f';
            app.EditField_4.Value = 130;
            app.CycleMinLabel = uilabel(app.MotionCompensationTab);
            app.CycleMinLabel.Text = 'Cycle/Min';
            app.CycleMinLabel_2 = uilabel(app.MotionCompensationTab);
            app.CycleMinLabel_2.Text = 'Cycle/Min';
            app.RunAllButton_3 = uibutton(app.MotionCompensationTab, 'push');
            app.RunAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunAllButton_3Pushed, true);
            app.RunAllButton_3.Enable = 'off';
            app.RunAllButton_3.Text = 'Run All';
            app.RemoveBinsButton = uibutton(app.MotionCompensationTab, 'push');
            app.RemoveBinsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBinsButtonPushed, true);
            app.RemoveBinsButton.Enable = 'off';
            app.RemoveBinsButton.Text = 'Remove Bins';
            app.Label_5 = uilabel(app.MotionCompensationTab);
            app.Label_5.Text = '/';
            app.TensorTab = uitab(app.TabGroup);
            app.TensorTab.Title = 'Tensor';
            app.UIAxes_10 = uiaxes(app.TensorTab);
            app.UIAxes_10.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_10.XTickLabel = '';
            app.UIAxes_10.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_10.YTickLabel = '';
            app.AutolambdaCheckBox = uicheckbox(app.TensorTab);
            app.AutolambdaCheckBox.Text = ' Auto lambda';
            app.AutolambdaCheckBox.Value = true;
            app.TVReconButton = uibutton(app.TensorTab, 'push');
            app.TVReconButton.ButtonPushedFcn = createCallbackFcn(app, @TVReconButtonPushed, true);
            app.TVReconButton.Enable = 'off';
            app.TVReconButton.Text = {'TV'; 'Recon'};
            app.DimensionReductionButtonGroup = uibuttongroup(app.TensorTab);
            app.DimensionReductionButtonGroup.Title = 'Dimension Reduction';
            app.NoneButton = uiradiobutton(app.DimensionReductionButtonGroup);
            app.NoneButton.Text = 'None';
            app.navButton = uiradiobutton(app.DimensionReductionButtonGroup);
            app.navButton.Text = 'nav';
            app.navButton.Value = true;
            app.cLButton = uiradiobutton(app.DimensionReductionButtonGroup);
            app.cLButton.Text = 'cL';
            app.TensorSubspaceButton = uibutton(app.TensorTab, 'push');
            app.TensorSubspaceButton.ButtonPushedFcn = createCallbackFcn(app, @TensorSubspaceButtonPushed, true);
            app.TensorSubspaceButton.Enable = 'off';
            app.TensorSubspaceButton.Text = {'Tensor '; 'Subspace'};
            app.CardiacLabel = uilabel(app.TensorTab);
            app.CardiacLabel.Text = '(Cardiac),';
            app.Lamp_10Label = uilabel(app.TensorTab);
            app.Lamp_10Label.Text = '';
            app.Lamp_10 = uilamp(app.TensorTab);
            app.Lamp_12Label = uilabel(app.TensorTab);
            app.Lamp_12Label.Text = '';
            app.Lamp_12 = uilamp(app.TensorTab);
            app.Lamp_13Label = uilabel(app.TensorTab);
            app.Lamp_13Label.Text = '';
            app.Lamp_13 = uilamp(app.TensorTab);
            app.lrLabel = uilabel(app.TensorTab);
            app.lrLabel.Text = 'lr = ';
            app.lrEditField = uieditfield(app.TensorTab, 'numeric');
            app.lrEditField.Limits = [0 1];
            app.lrEditField.ValueDisplayFormat = '%2.3g';
            app.lrEditField.Value = 5e-10;
            app.smsLabel = uilabel(app.TensorTab);
            app.smsLabel.Text = ',  sms = ';
            app.smsEditField = uieditfield(app.TensorTab, 'numeric');
            app.smsEditField.Limits = [0 1];
            app.smsEditField.ValueDisplayFormat = '%2.3g';
            app.smsEditField.Value = 3e-06;
            app.SetupTensorParametersButton = uibutton(app.TensorTab, 'push');
            app.SetupTensorParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetupTensorParametersButtonPushed, true);
            app.SetupTensorParametersButton.Enable = 'off';
            app.SetupTensorParametersButton.Text = {'Setup Tensor'; 'Parameters'};
            app.smsEditField_2 = uieditfield(app.TensorTab, 'numeric');
            app.smsEditField_2.Limits = [0 1];
            app.smsEditField_2.ValueDisplayFormat = '%2.3g';
            app.smsEditField_2.Value = 1e-06;
            app.PlayMovieButton_3 = uibutton(app.TensorTab, 'push');
            app.PlayMovieButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayMovieButton_3Pushed, true);
            app.PlayMovieButton_3.Enable = 'off';
            app.PlayMovieButton_3.Text = 'Play Movie';
            app.TimeSlider_2Label = uilabel(app.TensorTab);
            app.TimeSlider_2Label.Text = 'Time';
            app.TimeSlider_2 = uislider(app.TensorTab);
            app.TimeSlider_2.Limits = [1 512];
            app.TimeSlider_2.MajorTicks = [];
            app.TimeSlider_2.MajorTickLabels = {};
            app.TimeSlider_2.ValueChangedFcn = createCallbackFcn(app, @TimeSlider_2ValueChanged, true);
            app.TimeSlider_2.ValueChangingFcn = createCallbackFcn(app, @TimeSlider_2ValueChanging, true);
            app.TimeSlider_2.MinorTicks = [];
            app.TimeSlider_2.Enable = 'off';
            app.TimeSlider_2.Value = 1;
            app.SliceSlider_5Label = uilabel(app.TensorTab);
            app.SliceSlider_5Label.Text = 'Slice';
            app.SliceSlider_5 = uislider(app.TensorTab);
            app.SliceSlider_5.Limits = [1 96];
            app.SliceSlider_5.MajorTicks = [];
            app.SliceSlider_5.MajorTickLabels = {};
            app.SliceSlider_5.ValueChangedFcn = createCallbackFcn(app, @SliceSlider_5ValueChanged, true);
            app.SliceSlider_5.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_5ValueChanging, true);
            app.SliceSlider_5.MinorTicks = [];
            app.SliceSlider_5.Enable = 'off';
            app.SliceSlider_5.Value = 1;
            app.DropDown_4Label = uilabel(app.TensorTab);
            app.DropDown_4Label.Enable = 'off';
            app.DropDown_4Label.Text = '';
            app.DropDown_4 = uidropdown(app.TensorTab);
            app.DropDown_4.Items = {'Tensor', 'TV'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Enable = 'off';
            app.DropDown_4.Value = 'Tensor';
            app.WWSlider_3Label = uilabel(app.TensorTab);
            app.WWSlider_3Label.Text = 'WW';
            app.WWSlider_3 = uislider(app.TensorTab);
            app.WWSlider_3.Limits = [0 2];
            app.WWSlider_3.MajorTicks = [];
            app.WWSlider_3.MajorTickLabels = {};
            app.WWSlider_3.ValueChangingFcn = createCallbackFcn(app, @WWSlider_3ValueChanging, true);
            app.WWSlider_3.MinorTicks = [];
            app.WWSlider_3.Enable = 'off';
            app.WWSlider_3.Value = 1;
            app.DropDown_5 = uidropdown(app.TensorTab);
            app.DropDown_5.Items = {'Recovery', 'Cardiac Motion', 'Resp Motion'};
            app.DropDown_5.ItemsData = {'2', '3', '4'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Enable = 'off';
            app.DropDown_5.Value = '2';
            app.AutolambdaCheckBox_2 = uicheckbox(app.TensorTab);
            app.AutolambdaCheckBox_2.Text = ' Auto lambda';
            app.lambdaLabel = uilabel(app.TensorTab);
            app.lambdaLabel.Text = 'lambda =';
            app.lambdaEditField = uieditfield(app.TensorTab, 'numeric');
            app.lambdaEditField.Limits = [0 1];
            app.lambdaEditField.ValueDisplayFormat = '%2.3g';
            app.lambdaEditField.Value = 5e-11;
            app.RespLabel = uilabel(app.TensorTab);
            app.RespLabel.Text = '(Resp)';
            app.MaxIterationsEditFieldLabel = uilabel(app.TensorTab);
            app.MaxIterationsEditFieldLabel.Text = 'Max. Iterations';
            app.MaxIterationsEditField = uieditfield(app.TensorTab, 'numeric');
            app.MaxIterationsEditField.Limits = [1 100];
            app.MaxIterationsEditField.ValueDisplayFormat = '%.0f';
            app.MaxIterationsEditField.Value = 25;
            app.RankEditField_2Label = uilabel(app.TensorTab);
            app.RankEditField_2Label.Text = 'Rank';
            app.RankEditField_2 = uieditfield(app.TensorTab, 'numeric');
            app.RankEditField_2.Limits = [1 72];
            app.RankEditField_2.ValueDisplayFormat = '%.0f';
            app.RankEditField_2.Value = 6;
            app.UseToeplitzCheckBox = uicheckbox(app.TensorTab);
            app.UseToeplitzCheckBox.Text = 'Use Toeplitz';
            app.BlackBloodButton = uibutton(app.TensorTab, 'state');
            app.BlackBloodButton.ValueChangedFcn = createCallbackFcn(app, @BlackBloodButtonValueChanged, true);
            app.BlackBloodButton.Enable = 'off';
            app.BlackBloodButton.Text = 'Black Blood';
            app.RunAllButton_2 = uibutton(app.TensorTab, 'push');
            app.RunAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunAllButton_2Pushed, true);
            app.RunAllButton_2.Enable = 'off';
            app.RunAllButton_2.Text = 'Run All';
            app.SaveGifButton_3 = uibutton(app.TensorTab, 'push');
            app.SaveGifButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveGifButton_3Pushed, true);
            app.SaveGifButton_3.Enable = 'off';
            app.SaveGifButton_3.Text = 'Save Gif';
            app.SmallerlambdamoredetailsLargerlambdasmootherimageLabel = uilabel(app.TensorTab);
            app.SmallerlambdamoredetailsLargerlambdasmootherimageLabel.Text = {'Smaller lambda - more details'; 'Larger lambda   - smoother image'};
            app.FittingTab = uitab(app.TabGroup);
            app.FittingTab.Title = 'Fitting';
            app.UIAxes_13 = uiaxes(app.FittingTab);
            app.UIAxes_13.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_13.XTickLabel = '';
            app.UIAxes_13.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_13.YTickLabel = '';
            app.MaskHigherlevellargermaskButtonGroup = uibuttongroup(app.FittingTab);
            app.MaskHigherlevellargermaskButtonGroup.Title = 'Mask (Higher level = larger mask)';
            app.Level1Button = uiradiobutton(app.MaskHigherlevellargermaskButtonGroup);
            app.Level1Button.Text = 'Level 1';
            app.Level1Button.Value = true;
            app.Level2Button = uiradiobutton(app.MaskHigherlevellargermaskButtonGroup);
            app.Level2Button.Text = 'Level 2';
            app.Level3Button = uiradiobutton(app.MaskHigherlevellargermaskButtonGroup);
            app.Level3Button.Text = 'Level 3';
            app.NoMaskButton = uiradiobutton(app.MaskHigherlevellargermaskButtonGroup);
            app.NoMaskButton.Text = 'No Mask';
            app.BETButton = uiradiobutton(app.MaskHigherlevellargermaskButtonGroup);
            app.BETButton.Enable = 'off';
            app.BETButton.Text = 'BET';
            app.FittingstartatframeEditFieldLabel = uilabel(app.FittingTab);
            app.FittingstartatframeEditFieldLabel.Text = 'Fitting start at frame';
            app.FittingstartatframeEditField = uieditfield(app.FittingTab, 'numeric');
            app.FittingstartatframeEditField.Limits = [1 Inf];
            app.FittingstartatframeEditField.Value = 3;
            app.SkipframeEditFieldLabel = uilabel(app.FittingTab);
            app.SkipframeEditFieldLabel.Text = 'Skip frame';
            app.SkipframeEditField = uieditfield(app.FittingTab, 'numeric');
            app.SkipframeEditField.Limits = [1 100];
            app.SkipframeEditField.Value = 1;
            app.RespPhaseEditFieldLabel = uilabel(app.FittingTab);
            app.RespPhaseEditFieldLabel.Text = 'Resp Phase';
            app.RespPhaseEditField = uieditfield(app.FittingTab, 'text');
            app.RespPhaseEditField.Value = '1';
            app.CardiacPhaseEditFieldLabel = uilabel(app.FittingTab);
            app.CardiacPhaseEditFieldLabel.Text = 'Cardiac Phase';
            app.CardiacPhaseEditField = uieditfield(app.FittingTab, 'text');
            app.CardiacPhaseEditField.Value = '1';
            app.SlicesEditFieldLabel = uilabel(app.FittingTab);
            app.SlicesEditFieldLabel.Text = 'Slices';
            app.SlicesEditField = uieditfield(app.FittingTab, 'text');
            app.SlicesEditField.Value = '1:end';
            app.TotalFramesEditFieldLabel = uilabel(app.FittingTab);
            app.TotalFramesEditFieldLabel.Text = 'Total Frames';
            app.TotalFramesEditField = uieditfield(app.FittingTab, 'numeric');
            app.TotalFramesEditField.Limits = [1 Inf];
            app.TotalFramesEditField.Editable = 'off';
            app.TotalFramesEditField.Value = 512;
            app.EditField7 = uieditfield(app.FittingTab, 'numeric');
            app.EditField7.Limits = [50 Inf];
            app.EditField7.Value = 100;
            app.EditField8 = uieditfield(app.FittingTab, 'numeric');
            app.EditField8.Limits = [50 Inf];
            app.EditField8.Value = 5000;
            app.EditField10 = uieditfield(app.FittingTab, 'numeric');
            app.EditField10.Limits = [5 Inf];
            app.EditField10.Value = 10;
            app.EditField11 = uieditfield(app.FittingTab, 'numeric');
            app.EditField11.Limits = [5 Inf];
            app.EditField11.Value = 300;
            app.EditField13 = uieditfield(app.FittingTab, 'numeric');
            app.EditField13.Limits = [0 1];
            app.EditField13.Value = 0.5;
            app.EditField14 = uieditfield(app.FittingTab, 'numeric');
            app.EditField14.Limits = [0 1];
            app.EditField14.Value = 1;
            app.EditField16 = uieditfield(app.FittingTab, 'numeric');
            app.EditField16.Limits = [0.01 1];
            app.EditField16.Value = 0.5;
            app.EditField17 = uieditfield(app.FittingTab, 'numeric');
            app.EditField17.Limits = [0.01 1];
            app.EditField17.Value = 1;
            app.FitParametersButtonGroup = uibuttongroup(app.FittingTab);
            app.FitParametersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FitParametersButtonGroupSelectionChanged, true);
            app.FitParametersButtonGroup.Title = 'Fit Parameters';
            app.AllButton = uiradiobutton(app.FitParametersButtonGroup);
            app.AllButton.Text = ' All';
            app.ConstantbetaButton = uiradiobutton(app.FitParametersButtonGroup);
            app.ConstantbetaButton.Text = 'Constant beta';
            app.SingleInvEfficiencyButton = uiradiobutton(app.FitParametersButtonGroup);
            app.SingleInvEfficiencyButton.Text = 'Single Inv Efficiency';
            app.SingleInvEfficiencyButton.Value = true;
            app.StartFittingButton = uibutton(app.FittingTab, 'push');
            app.StartFittingButton.ButtonPushedFcn = createCallbackFcn(app, @StartFittingButtonPushed, true);
            app.StartFittingButton.Enable = 'off';
            app.StartFittingButton.Text = 'Start Fitting';
            app.InitialValueMinMaxLabel = uilabel(app.FittingTab);
            app.InitialValueMinMaxLabel.Text = 'Initial Value                 Min                  Max';
            app.CheckFittingResultButton = uibutton(app.FittingTab, 'push');
            app.CheckFittingResultButton.ButtonPushedFcn = createCallbackFcn(app, @CheckFittingResultButtonPushed, true);
            app.CheckFittingResultButton.Enable = 'off';
            app.CheckFittingResultButton.Text = 'Check Fitting Result';
            app.SaveFittingResultButton = uibutton(app.FittingTab, 'push');
            app.SaveFittingResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFittingResultButtonPushed, true);
            app.SaveFittingResultButton.Enable = 'off';
            app.SaveFittingResultButton.Text = 'Save Fitting Result';
            app.T1EditField_2Label = uilabel(app.FittingTab);
            app.T1EditField_2Label.Text = 'T1';
            app.T1EditField_2 = uieditfield(app.FittingTab, 'numeric');
            app.T1EditField_2.Limits = [100 3000];
            app.T1EditField_2.Value = 1000;
            app.T2EditField_2Label = uilabel(app.FittingTab);
            app.T2EditField_2Label.Text = 'T2';
            app.T2EditField_2 = uieditfield(app.FittingTab, 'numeric');
            app.T2EditField_2.Limits = [5 Inf];
            app.T2EditField_2.Value = 50;
            app.InvEfficiencyEditFieldLabel = uilabel(app.FittingTab);
            app.InvEfficiencyEditFieldLabel.Text = 'Inv Efficiency';
            app.InvEfficiencyEditField = uieditfield(app.FittingTab, 'numeric');
            app.InvEfficiencyEditField.Limits = [0 1];
            app.InvEfficiencyEditField.Value = 1;
            app.B1betaEditFieldLabel = uilabel(app.FittingTab);
            app.B1betaEditFieldLabel.Text = 'B1 (beta)';
            app.B1betaEditField = uieditfield(app.FittingTab, 'numeric');
            app.B1betaEditField.Limits = [0.01 1];
            app.B1betaEditField.Value = 0.9;
            app.Lamp_15Label = uilabel(app.FittingTab);
            app.Lamp_15Label.Text = '';
            app.Lamp_15 = uilamp(app.FittingTab);
            app.SaveGifButton_4 = uibutton(app.FittingTab, 'push');
            app.SaveGifButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveGifButton_4Pushed, true);
            app.SaveGifButton_4.Enable = 'off';
            app.SaveGifButton_4.Text = 'Save Gif';
            app.PlayMovieButton_4 = uibutton(app.FittingTab, 'push');
            app.PlayMovieButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlayMovieButton_4Pushed, true);
            app.PlayMovieButton_4.Enable = 'off';
            app.PlayMovieButton_4.Text = 'Play Movie';
            app.PhaseSliderLabel = uilabel(app.FittingTab);
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.FittingTab);
            app.PhaseSlider.Limits = [1 512];
            app.PhaseSlider.MajorTicks = [];
            app.PhaseSlider.MajorTickLabels = {};
            app.PhaseSlider.ValueChangedFcn = createCallbackFcn(app, @PhaseSliderValueChanged, true);
            app.PhaseSlider.ValueChangingFcn = createCallbackFcn(app, @PhaseSliderValueChanging, true);
            app.PhaseSlider.MinorTicks = [];
            app.PhaseSlider.Enable = 'off';
            app.PhaseSlider.Value = 1;
            app.SliceSlider_6Label = uilabel(app.FittingTab);
            app.SliceSlider_6Label.Text = 'Slice';
            app.SliceSlider_6 = uislider(app.FittingTab);
            app.SliceSlider_6.Limits = [1 96];
            app.SliceSlider_6.MajorTicks = [];
            app.SliceSlider_6.MajorTickLabels = {};
            app.SliceSlider_6.ValueChangedFcn = createCallbackFcn(app, @SliceSlider_6ValueChanged, true);
            app.SliceSlider_6.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_6ValueChanging, true);
            app.SliceSlider_6.MinorTicks = [];
            app.SliceSlider_6.Enable = 'off';
            app.SliceSlider_6.Value = 1;
            app.DropDown_6Label = uilabel(app.FittingTab);
            app.DropDown_6Label.Enable = 'off';
            app.DropDown_6Label.Text = '';
            app.DropDown_6 = uidropdown(app.FittingTab);
            app.DropDown_6.Items = {'T1', 'T2'};
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Enable = 'off';
            app.DropDown_6.Value = 'T1';
            app.stepfittingCheckBox = uicheckbox(app.FittingTab);
            app.stepfittingCheckBox.Enable = 'off';
            app.stepfittingCheckBox.Text = ' 2-step fitting';
            app.LoadFitParamsButton = uibutton(app.FittingTab, 'push');
            app.LoadFitParamsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFitParamsButtonPushed, true);
            app.LoadFitParamsButton.Text = 'Load FitParams';
            app.SelectButton = uibutton(app.MultitaskingReconUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.FileEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.FileEditFieldLabel.Text = 'File:';
            app.FileEditField = uieditfield(app.MultitaskingReconUIFigure, 'text');
            app.FileEditField.Editable = 'off';
            app.StatusEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.MultitaskingReconUIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            app.StatusEditField.Value = 'Please setect rawdata or saved recon array';
            app.TrajectoryEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.TrajectoryEditFieldLabel.Text = 'Trajectory';
            app.TrajectoryEditField = uieditfield(app.MultitaskingReconUIFigure, 'text');
            app.TrajectoryEditField.ValueChangedFcn = createCallbackFcn(app, @TrajectoryEditFieldValueChanged, true);
            app.TrajectoryEditField.Editable = 'off';
            app.TRmsEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.TRmsEditFieldLabel.Text = 'TR (ms)';
            app.TRmsEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.TRmsEditField.Limits = [0 Inf];
            app.TRmsEditField.Editable = 'off';
            app.TEmsEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.TEmsEditFieldLabel.Text = 'TE (ms)';
            app.TEmsEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.TEmsEditField.Editable = 'off';
            app.EchospacingmsEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.EchospacingmsEditFieldLabel.Text = 'Echo spacing (ms)';
            app.EchospacingmsEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.NAcqEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.NAcqEditFieldLabel.Text = 'NAcq';
            app.NAcqEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.NAcqEditField.RoundFractionalValues = 'on';
            app.NAcqEditField.ValueDisplayFormat = '%.0f';
            app.NAcqEditField.Editable = 'off';
            app.NkxEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.NkxEditFieldLabel.Text = 'Nkx';
            app.NkxEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.NkxEditField.RoundFractionalValues = 'on';
            app.NkxEditField.ValueDisplayFormat = '%.0f';
            app.NkxEditField.Editable = 'off';
            app.FOVrommEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.FOVrommEditFieldLabel.Text = 'FOVro (mm)';
            app.FOVrommEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.FOVrommEditField.Limits = [0 Inf];
            app.FOVrommEditField.ValueDisplayFormat = '%4.1f';
            app.FOVrommEditField.Editable = 'off';
            app.NxEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.NxEditFieldLabel.Text = 'Nx';
            app.NxEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.NxEditField.Limits = [0 Inf];
            app.NxEditField.RoundFractionalValues = 'on';
            app.NxEditField.ValueDisplayFormat = '%.0f';
            app.NyEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NyEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.NyEditField.Limits = [0 Inf];
            app.NyEditField.RoundFractionalValues = 'on';
            app.NyEditField.ValueDisplayFormat = '%.0f';
            app.NzEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.NzEditFieldLabel.Text = 'Nz';
            app.NzEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.NzEditField.Limits = [0 Inf];
            app.NzEditField.RoundFractionalValues = 'on';
            app.NzEditField.ValueDisplayFormat = '%.0f';
            app.NcoilsEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.NcoilsEditFieldLabel.Text = 'Ncoils';
            app.NcoilsEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.NcoilsEditField.RoundFractionalValues = 'on';
            app.NcoilsEditField.ValueDisplayFormat = '%.0f';
            app.NcoilsEditField.Editable = 'off';
            app.T2IRmsEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.T2IRmsEditFieldLabel.Text = 'T2-IR (ms)';
            app.T2IRmsEditField = uieditfield(app.MultitaskingReconUIFigure, 'text');
            app.T2IRmsEditField.ValueChangedFcn = createCallbackFcn(app, @T2IRmsEditFieldValueChanged, true);
            app.T2IRmsEditField.Value = '30 40 50 60';
            app.IREditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.IREditFieldLabel.Text = 'IR';
            app.IREditField = uieditfield(app.MultitaskingReconUIFigure, 'text');
            app.IREditField.ValueChangedFcn = createCallbackFcn(app, @IREditFieldValueChanged, true);
            app.IREditField.Value = 'on';
            app.UseGPUCheckBox = uicheckbox(app.MultitaskingReconUIFigure);
            app.UseGPUCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseGPUCheckBoxValueChanged, true);
            app.UseGPUCheckBox.Enable = 'off';
            app.UseGPUCheckBox.Text = '  Use GPU';
            app.ScanTypeEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.ScanTypeEditFieldLabel.Text = 'Scan Type';
            app.ScanTypeEditField = uieditfield(app.MultitaskingReconUIFigure, 'text');
            app.ScanTypeEditField.Editable = 'off';
            app.MBFactorEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.MBFactorEditFieldLabel.Text = 'MB Factor';
            app.MBFactorEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.MBFactorEditField.Limits = [1 3];
            app.MBFactorEditField.RoundFractionalValues = 'on';
            app.MBFactorEditField.ValueDisplayFormat = '%.0f';
            app.MBFactorEditField.ValueChangedFcn = createCallbackFcn(app, @MBFactorEditFieldValueChanged, true);
            app.MBFactorEditField.Value = 1;
            app.SGBlockEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.SGBlockEditFieldLabel.Text = 'SG Block';
            app.SGBlockEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.SGBlockEditField.Limits = [2 100];
            app.SGBlockEditField.RoundFractionalValues = 'on';
            app.SGBlockEditField.ValueDisplayFormat = '%.0f';
            app.SGBlockEditField.ValueChangedFcn = createCallbackFcn(app, @SGBlockEditFieldValueChanged, true);
            app.SGBlockEditField.Value = 2;
            app.T1rhoprepmsEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.T1rhoprepmsEditFieldLabel.Text = 'T1rho prep (ms)';
            app.T1rhoprepmsEditField = uieditfield(app.MultitaskingReconUIFigure, 'text');
            app.LinesperShotEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.LinesperShotEditFieldLabel.Text = 'Lines per Shot';
            app.LinesperShotEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.LinesperShotEditField.Limits = [1 50000];
            app.LinesperShotEditField.RoundFractionalValues = 'on';
            app.LinesperShotEditField.ValueDisplayFormat = '%.0f';
            app.LinesperShotEditField.ValueChangedFcn = createCallbackFcn(app, @LinesperShotEditFieldValueChanged, true);
            app.LinesperShotEditField.Value = 1;
            app.NEchoEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.NEchoEditFieldLabel.Text = 'NEcho';
            app.NEchoEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.NEchoEditField.RoundFractionalValues = 'on';
            app.NEchoEditField.ValueDisplayFormat = '%.0f';
            app.NEchoEditField.Editable = 'off';
            app.NkyEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.NkyEditFieldLabel.Text = 'Nky';
            app.NkyEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.NkyEditField.RoundFractionalValues = 'on';
            app.NkyEditField.ValueDisplayFormat = '%.0f';
            app.NkyEditField.ValueChangedFcn = createCallbackFcn(app, @NkyEditFieldValueChanged, true);
            app.NkzEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.NkzEditFieldLabel.Text = 'Nkz';
            app.NkzEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.NkzEditField.RoundFractionalValues = 'on';
            app.NkzEditField.ValueDisplayFormat = '%.0f';
            app.ImagingAreaButtonGroup = uibuttongroup(app.MultitaskingReconUIFigure);
            app.ImagingAreaButtonGroup.Title = 'Imaging Area';
            app.ChestButton = uiradiobutton(app.ImagingAreaButtonGroup);
            app.ChestButton.Enable = 'off';
            app.ChestButton.Text = 'Chest';
            app.AbdomenButton = uiradiobutton(app.ImagingAreaButtonGroup);
            app.AbdomenButton.Enable = 'off';
            app.AbdomenButton.Text = 'Abdomen';
            app.HeadNeckButton = uiradiobutton(app.ImagingAreaButtonGroup);
            app.HeadNeckButton.Text = 'Head/Neck';
            app.HeadNeckButton.Value = true;
            app.DCECheckBox = uicheckbox(app.MultitaskingReconUIFigure);
            app.DCECheckBox.Enable = 'off';
            app.DCECheckBox.Text = 'DCE';
            app.LoadReconSettingButton = uibutton(app.MultitaskingReconUIFigure, 'push');
            app.LoadReconSettingButton.Enable = 'off';
            app.LoadReconSettingButton.Text = 'Load Recon Setting';
            app.SaveWorkspaceButton = uibutton(app.MultitaskingReconUIFigure, 'push');
            app.SaveWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWorkspaceButtonPushed, true);
            app.SaveWorkspaceButton.Enable = 'off';
            app.SaveWorkspaceButton.Text = {'Save'; 'Workspace'};
            app.ExportDICOMButton = uibutton(app.MultitaskingReconUIFigure, 'push');
            app.ExportDICOMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDICOMButtonPushed, true);
            app.ExportDICOMButton.Enable = 'off';
            app.ExportDICOMButton.Text = {'Export'; 'DICOM'};
            app.FlipAngleEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.FlipAngleEditFieldLabel.Text = 'Flip Angle';
            app.FlipAngleEditField = uieditfield(app.MultitaskingReconUIFigure, 'text');
            app.FlipAngleEditField.ValueChangedFcn = createCallbackFcn(app, @FlipAngleEditFieldValueChanged, true);
            app.FOVpemmEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.FOVpemmEditFieldLabel.Text = 'FOVpe (mm)';
            app.FOVpemmEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.FOVpemmEditField.Limits = [0 Inf];
            app.FOVpemmEditField.ValueDisplayFormat = '%4.1f';
            app.FOVpemmEditField.Editable = 'off';
            app.LicenseIDLabel = uilabel(app.MultitaskingReconUIFigure);
            app.LicenseIDLabel.Text = 'License ID';
            app.RefreshButton = uibutton(app.MultitaskingReconUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.SliceThicknessmmEditFieldLabel = uilabel(app.MultitaskingReconUIFigure);
            app.SliceThicknessmmEditFieldLabel.Text = 'Slice Thickness (mm)';
            app.SliceThicknessmmEditField = uieditfield(app.MultitaskingReconUIFigure, 'numeric');
            app.SliceThicknessmmEditField.Limits = [0 Inf];
            app.SliceThicknessmmEditField.RoundFractionalValues = 'on';
            app.SliceThicknessmmEditField.ValueDisplayFormat = '%2.2f';
            % Show the figure after all components are created
            app.MultitaskingReconUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultitaskingReconApp_qMATCH_R2022b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MultitaskingReconUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MultitaskingReconUIFigure)
        end
    end
end